classdef DataConversionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataConversionAppUIFigure  matlab.ui.Figure
        groupingPanel              matlab.ui.container.Panel
        fileNamesList              matlab.ui.control.ListBox
        dataLabel                  matlab.ui.control.Label
        loadButton                 matlab.ui.control.Button
        signalPanel                matlab.ui.container.Panel
        signalsList                matlab.ui.control.ListBox
        visualizeButton            matlab.ui.control.Button
        exportPanel                matlab.ui.container.Panel
        saveDataMatlabButton       matlab.ui.control.Button
        saveDataText               matlab.ui.control.Button
        plotAxes                   matlab.ui.control.UIAxes
        supportedFormatsText       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        %UI
        uiHandles;
        figureHandle;
        plotHandle;
        
        %data
        dataFile;
    end
    
    methods (Access = private)
        
        function plotData(app)
            if isempty(app.dataFile)
                if ~isempty(app.plotHandle)
                    delete(app.plotHandle);
                    app.plotHandle = [];
                    cla(app.plotHandle);
                end
            else
                selectedIdxs = app.getSelectedColumnIdxs();
                app.plotHandle = plot(app.plotAxes,app.dataFile.data(:,selectedIdxs));
            end
        end
        
        function populateFileNamesList(app)
            files = [dir(fullfile(Constants.kDataPath, '*.mat'));...
                dir(fullfile(Constants.kDataPath, '*.txt'))];
            
            nFiles = length(files);
            filesStr = cell(1,nFiles);
            
            for i = 1 : length(files)
                filesStr{i} = files(i).name;
            end
            
            app.fileNamesList.Items = filesStr;
        end
        
        
        function handleSelectionChanged(app,~,~)
            app.updateFileName();
        end
        
        function loadData(app)
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                app.dataFile = DataLoader.LoadDataFile(fileName);
            end
        end
        
        %ui
        
        function selectedColumnIdxs = getSelectedColumnIdxs(app)
            selectedColumnIdxsStr = app.signalsList.Value;
            [~,selectedColumnIdxs] = ismember(selectedColumnIdxsStr,app.signalsList.Items);
        end
        
        
        function updateSignalsList(app)
            if ~isempty(app.dataFile)
                app.signalsList.Items = app.dataFile.columnNames;
                app.signalsList.Value = app.dataFile.columnNames{1};
            end
        end
        
        function updateDataLabel(app)
            
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                app.dataLabel.Text = sprintf('%d samples\n',app.dataFile.numRows);
            end
        end
    
        %{
        function cutData(app)
            startSample = app.getStartSample();
            endSample = app.getEndSample();
            numSamples = size(app.data,1);
            if startSample > 0 && startSample < endSample && endSample <= numSamples
                app.data = app.data(startSample:endSample,:);
                app.plotData();
                app.updateEndText();
            end
        
        end
        %}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            app.populateFileNamesList();
            app.loadData();
            app.updateSignalsList();
            app.plotData();
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            if ~isempty(app.dataFile)
                app.dataFile = [];
            end
            
            app.loadData();
            app.updateDataLabel();
            app.updateSignalsList();
            app.plotData();
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)
            app.plotData();
        end

        % Button pushed function: saveDataMatlabButton
        function saveDataMatlabButtonPushed(app, event)
            
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                app.dataFile.fileName = fileName;
                app.dataFile.fileName = Helper.removeFileExtension(app.dataFile.fileName);
                DataLoader.SaveDataFileBinary(app.dataFile);
            end
        end

        % Button pushed function: saveDataText
        function saveDataTextButtonPushed(app, event)
            
            fileName = app.fileNamesList.Value;
            if ~isempty(fileName)
                fileName = Helper.removeFileExtension(fileName);
                DataLoader.SaveDataFileText(app.dataFile.data,app.dataFile.columnNames,fileName);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataConversionAppUIFigure = uifigure('Visible', 'off');
            app.dataConversionAppUIFigure.Name = 'Data Conversion App';
            app.groupingPanel = uipanel(app.dataConversionAppUIFigure);
            app.groupingPanel.Title = 'Data';
            app.fileNamesList = uilistbox(app.groupingPanel);
            app.fileNamesList.Items = {};
            app.fileNamesList.Value = {};
            app.dataLabel = uilabel(app.groupingPanel);
            app.dataLabel.Text = '';
            app.loadButton = uibutton(app.groupingPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load';
            app.signalPanel = uipanel(app.dataConversionAppUIFigure);
            app.signalPanel.Title = 'Signal';
            app.signalsList = uilistbox(app.signalPanel);
            app.signalsList.Items = {};
            app.signalsList.Value = {};
            app.visualizeButton = uibutton(app.signalPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.exportPanel = uipanel(app.dataConversionAppUIFigure);
            app.exportPanel.Title = 'Export';
            app.saveDataMatlabButton = uibutton(app.exportPanel, 'push');
            app.saveDataMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataMatlabButtonPushed, true);
            app.saveDataMatlabButton.Text = 'Save Data Binary';
            app.saveDataText = uibutton(app.exportPanel, 'push');
            app.saveDataText.ButtonPushedFcn = createCallbackFcn(app, @saveDataTextButtonPushed, true);
            app.saveDataText.Text = 'Save Data ASCII';
            app.plotAxes = uiaxes(app.dataConversionAppUIFigure);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            app.supportedFormatsText = uitextarea(app.dataConversionAppUIFigure);
            app.supportedFormatsText.Editable = 'off';
            app.supportedFormatsText.Value = {'supported formats are: .mat and .txt'};
            % Show the figure after all components are created
            app.dataConversionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataConversionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataConversionAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataConversionAppUIFigure)
        end
    end
end