classdef AutoAnnotateDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        UnsupervisedAnnotationPanel     matlab.ui.container.Panel
        clusterAnnotationsButton        matlab.ui.control.Button
        SimilarityToleranceEditFieldLabel  matlab.ui.control.Label
        clusteringSimilarityToleranceEditField  matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        SemiSupervisedAnnotationPanel   matlab.ui.container.Panel
        suggestSimilarAnnotationsCheckBox  matlab.ui.control.CheckBox
        TextArea_2                      matlab.ui.control.TextArea
        SimilarityToleranceEditFieldLabel_2  matlab.ui.control.Label
        semiSupervisedSimilarityToleranceEditField  matlab.ui.control.NumericEditField
        segmentationAlgorithmsList      matlab.ui.control.ListBox
        SegmentationAlgorithmFileLabel  matlab.ui.control.Label
        ResetSuggestionsButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        numClasses; % Description
        delegate;
        annotationClusterer;
        currentSegmentationAlgorithm;
    end
    
    methods (Access = public)
        function setShouldSuggestAnnotations(app,b)
            app.suggestSimilarAnnotationsCheckBox.Value = b;
        end
        
        function b = shouldSuggestAnnotations(app)
            b = app.suggestSimilarAnnotationsCheckBox.Value;
        end
    end
    
    methods (Access = private)
        function tolerance = getClusteringSimilarityTolerance(app)
            tolerance = app.clusteringSimilarityToleranceEditField.Value;
        end
        
        function tolerance = getSemiSupervisedSimilarityTolerance(app)
            tolerance = app.semiSupervisedSimilarityToleranceEditField;
        end
        
        function loadCurrentSegmentationAlgorithm(app)
            fileName = app.segmentationAlgorithmsList.Value;
            app.currentSegmentationAlgorithm = DataLoader.LoadAlgorithm(fileName);
        end
        
        %fills the table and selects the first element
        function fillSegmentationFilesList(app)
            loadedARChainFiles = Helper.ListARChainFiles();
            app.segmentationAlgorithmsList.Items = loadedARChainFiles;
            
            if ~isempty(loadedARChainFiles)
                app.segmentationAlgorithmsList.Value = app.segmentationAlgorithmsList.Items{1};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, numClasses, delegate)
            app.numClasses = numClasses;
            app.delegate = delegate;
            
            app.fillSegmentationFilesList();
            
            app.loadCurrentSegmentationAlgorithm();
        end

        % Button pushed function: clusterAnnotationsButton
        function clusterAnnotationsButtonPushed(app, event)
            
            app.annotationClusterer = AnnotationClusterer(app.numClasses,app.currentSegmentationAlgorithm);
            
            app.annotationClusterer.distanceThreshold = app.getClusteringSimilarityTolerance();
            app.delegate.handleAnnotationClustersClicked(app.annotationClusterer);
        end

        % Value changed function: suggestSimilarAnnotationsCheckBox
        function suggestSimilarAnnotationsCheckBoxValueChanged(app, event)
            value = app.suggestSimilarAnnotationsCheckBox.Value;
            app.delegate.handleDidChangeSuggestAnnotations(value);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.delegate.handleAutoAnnotateDialogClosed();
        end

        % Value changed function: segmentationAlgorithmsList
        function segmentationAlgorithmsListValueChanged(app, event)
            app.loadCurrentSegmentationAlgorithm();
            if ~isempty(app.currentSegmentationAlgorithm)
                app.annotationClusterer.segmentationAlgorithm = app.currentSegmentationAlgorithm;
            end
        end

        % Value changed function: 
        % semiSupervisedSimilarityToleranceEditField
        function semiSupervisedSimilarityToleranceEditFieldValueChanged(app, event)
            value = app.semiSupervisedSimilarityToleranceEditField.Value;
            app.delegate.handleSemiSupervisedSimilarityToleranceChanged(value);
        end

        % Button pushed function: ResetSuggestionsButton
        function ResetSuggestionsButtonPushed(app, event)
            app.delegate.handleAutoAnnotationsReseted();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Auto-Annotate Dialog';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.UnsupervisedAnnotationPanel = uipanel(app.Figure);
            app.UnsupervisedAnnotationPanel.Title = 'Unsupervised Annotation';
            app.clusterAnnotationsButton = uibutton(app.UnsupervisedAnnotationPanel, 'push');
            app.clusterAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @clusterAnnotationsButtonPushed, true);
            app.clusterAnnotationsButton.Text = 'Cluster Annotations';
            app.SimilarityToleranceEditFieldLabel = uilabel(app.UnsupervisedAnnotationPanel);
            app.SimilarityToleranceEditFieldLabel.Text = 'Similarity Tolerance';
            app.clusteringSimilarityToleranceEditField = uieditfield(app.UnsupervisedAnnotationPanel, 'numeric');
            app.clusteringSimilarityToleranceEditField.Value = 20;
            app.TextArea = uitextarea(app.UnsupervisedAnnotationPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Creates clusters of annotations without any training data. The higher the tolerance, the more annotations it finds per cluster.'};
            app.SemiSupervisedAnnotationPanel = uipanel(app.Figure);
            app.SemiSupervisedAnnotationPanel.Title = 'Semi-Supervised Annotation';
            app.suggestSimilarAnnotationsCheckBox = uicheckbox(app.SemiSupervisedAnnotationPanel);
            app.suggestSimilarAnnotationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @suggestSimilarAnnotationsCheckBoxValueChanged, true);
            app.suggestSimilarAnnotationsCheckBox.Text = 'Suggest me similar annotations';
            app.TextArea_2 = uitextarea(app.SemiSupervisedAnnotationPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Uses Dynamic Time Warping to compare the last annotation to other possible annotations'};
            app.SimilarityToleranceEditFieldLabel_2 = uilabel(app.SemiSupervisedAnnotationPanel);
            app.SimilarityToleranceEditFieldLabel_2.Text = 'Similarity Tolerance';
            app.semiSupervisedSimilarityToleranceEditField = uieditfield(app.SemiSupervisedAnnotationPanel, 'numeric');
            app.semiSupervisedSimilarityToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @semiSupervisedSimilarityToleranceEditFieldValueChanged, true);
            app.semiSupervisedSimilarityToleranceEditField.Value = 0.0002;
            app.segmentationAlgorithmsList = uilistbox(app.Figure);
            app.segmentationAlgorithmsList.ValueChangedFcn = createCallbackFcn(app, @segmentationAlgorithmsListValueChanged, true);
            app.SegmentationAlgorithmFileLabel = uilabel(app.Figure);
            app.SegmentationAlgorithmFileLabel.Text = 'Segmentation Algorithm File';
            app.ResetSuggestionsButton = uibutton(app.Figure, 'push');
            app.ResetSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSuggestionsButtonPushed, true);
            app.ResetSuggestionsButton.Text = 'Reset Suggestions';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoAnnotateDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end