classdef PreprocessingDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                    matlab.ui.Figure
        preprocessingPanel        matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList  matlab.ui.control.ListBox
        computedSignalsBox        matlab.ui.control.ListBox
        addButton                 matlab.ui.control.Button
        removeButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        delegate;%delegate to notify about new algorithms selected
        
        dataFile;%DataFile containing signals
        
        preprocessingAlgorithms; % list of preprocessing algorithms added
        
        preprocessingConfigurator;
    end
    
    methods (Access = private)
        
        function updatePreprocessingAlgorithmsList(app)
            computedSignalStrs = Helper.ComputersToStringsArray(app.preprocessingAlgorithms);
            app.computedSignalsBox.Items = computedSignalStrs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataFile, delegate)
            
            %load the ui
            preprocessingComputers = Palette.PreprocessingComputers();
            preprocessingComputers = preprocessingComputers(2:end);
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                dataFile.columnNames,...
                preprocessingComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingSignalsList.Value = app.preprocessingSignalsList.Items{1};
            
            app.updatePreprocessingAlgorithmsList();
            
            app.dataFile = dataFile;
            app.delegate = delegate;
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            
            preprocessor = app.preprocessingConfigurator.createPreprocessingComputerWithUIParameters();
            if ~isempty(preprocessor)
                app.preprocessingAlgorithms{end+1} = preprocessor;
                
                app.updatePreprocessingAlgorithmsList();
                
                app.delegate.handlePreprocessedSignalsComputed(app.preprocessingAlgorithms,app);
            end
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.delegate.handlePreprocessingDialogClosed(app);
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            %get selected indices
            [~,selectedIndices] = ismember(app.computedSignalsBox.Value, app.computedSignalsBox.Items);
            
            %update algorithms list
            app.preprocessingAlgorithms(selectedIndices) = [];
            
            %update ui
            app.updatePreprocessingAlgorithmsList();
            
            %notify delegate
            app.delegate.handlePreprocessedSignalsComputed(app.preprocessingAlgorithms,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Preprocessing Dialog';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.preprocessingPanel = uipanel(app.Figure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.computedSignalsBox = uilistbox(app.Figure);
            app.computedSignalsBox.Items = {};
            app.computedSignalsBox.Value = {};
            app.addButton = uibutton(app.Figure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '>>';
            app.removeButton = uibutton(app.Figure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = '<<';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end