classdef EventDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                    matlab.ui.Figure
        preprocessingPanel            matlab.ui.container.Panel
        preprocessingAlgorithmsVariablesTable  matlab.ui.control.Table
        preprocessingAlgorithmsList   matlab.ui.control.ListBox
        preprocessingSignalsList      matlab.ui.control.ListBox
        annotationsPanel              matlab.ui.container.Panel
        annotationMappingList         matlab.ui.control.ListBox
        annotationMappingTable        matlab.ui.control.Table
        dataPanel                     matlab.ui.container.Panel
        filesList                     matlab.ui.control.ListBox
        selectAllFilesCheckBox        matlab.ui.control.CheckBox
        filesResultTable              matlab.ui.control.Table
        classesResultTable            matlab.ui.control.Table
        EventMatchingToleranceLabel   matlab.ui.control.Label
        toleranceTextBox              matlab.ui.control.NumericEditField
        executeButton                 matlab.ui.control.Button
        saveEventsButton              matlab.ui.control.Button
        clearCacheButton              matlab.ui.control.Button
        eventDetectionPanel           matlab.ui.container.Panel
        eventDetectionPreprocessingAlgorithmsVariablesTable  matlab.ui.control.Table
        eventDetectionPreprocessingAlgorithmsList  matlab.ui.control.ListBox
        eventDetectionPreprocessingSignalsList  matlab.ui.control.ListBox
        eventDetectionVariablesTable  matlab.ui.control.Table
        eventDetectionList            matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
        %data
        fileNames;
        annotations;
        annotationChain;
        arcChain;
        preprocessingChain;
        fileLoader;
        annotationsLoader;
        fileLoaderPreprocessing;
        classesMap;
        processedAnnotations;
        
        %detection
        eventDetectorConfigurator;
        eventsPerFile;
        
        %energies
        energiesPerFile;
        
        %labeling strategies
        labelMappingConfigurator;
        currentLabeling;
        labeler;
        
        %results
        resultsComputer;
        resultsPerFile;
        
        %ui plotting
        scrollPanel;
        eventsPlotter = [];
        preprocessingConfigurator;
        figureHandle;
        uiHandles;
        axesHandles;
    end
    
    properties (Access = public, Constant)
        kMaxFileNameLength = 12;
    end
    
    methods (Access = private)
        
        function idxs = getSelectedFileIdxs(app)
            selectedIdxsStr = app.filesList.Value;
            [~,idxs] = ismember(selectedIdxsStr,app.filesList.Items);
            idxs = sort(idxs);
        end
        function selectedFiles = getSelectedFiles(app)
            selectedIdxs = app.getSelectedFileIdxs();
            selectedFiles = app.filesList.Items(selectedIdxs);
        end
        
        function tolerance = getTolerance(app)
            tolerance = app.toleranceTextBox.Value;
        end
        
        function showResultTables(app)
            app.filesResultTable.Visible = true;
            app.classesResultTable.Visible = true;
        end
        
        function hideResultTables(app)
            app.filesResultTable.Visible = false;
            app.classesResultTable.Visible = false;
        end
        
        function updateFilesTable(app,selectedFileIdxs)
            
            nRows = length(app.resultsPerFile);
            resultsTable = cell(nRows+1,3);
            
            for i = 1 : nRows
                fileIdx = selectedFileIdxs(i);
                fileName = app.fileNames{fileIdx};
                fileResults = app.resultsPerFile(i);
                
                resultsTable{i,1} = fileName;
                resultsTable{i,2} = sprintf('%.2f%%',100 * fileResults.recall);
                resultsTable{i,3} = sprintf('x%.2f',fileResults.FPEventRate);
                resultsTable{i,4} = sprintf('%.2f%%',100 * fileResults.f1Score);
            end
            
            aggregatedFilesResults = DetectionAggregatedResults.DetectionAggregatedResultsWithDetectionResults(app.resultsPerFile);
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = sprintf('%.2f%%',100 * aggregatedFilesResults.nGoodEventsDetected / aggregatedFilesResults.nGoodEvents);
            resultsTable{nRows+1,3} = sprintf('x%.2f%',aggregatedFilesResults.nBadEventsDetected / aggregatedFilesResults.nGoodEvents);
            resultsTable{nRows+1,4} = sprintf('%.2f%%',100 * mean([fileResults.f1Score]));
            app.filesResultTable.Data = resultsTable;
        end
        
        function updateClassResultTable(app,classDetectionStatistics)
            
            
            nGoodEventsDetectedPerClass = classDetectionStatistics.nGoodEventsDetectedPerClass;
            nTotalGoodEventsPerClass = classDetectionStatistics.nGoodEventsPerClass;
            
            nRows = app.currentLabeling.numClasses;
            resultsTable = cell(nRows+1,3);
            
            rowIdx = 1;
            for i = 1 : nRows
                classStr = app.currentLabeling.classNames{i};
                detectedPerClass = nGoodEventsDetectedPerClass(i);
                if nTotalGoodEventsPerClass(i) == 0
                    detectionRate = 0;
                else
                    detectionRate = 100 * detectedPerClass / nTotalGoodEventsPerClass(i);
                end
                resultsTable{rowIdx,1} = classStr;
                resultsTable{rowIdx,2} = detectedPerClass;
                resultsTable{rowIdx,3} = sprintf('%.2f%%',detectionRate);
                rowIdx = rowIdx + 1;
                
            end
            
            totalGoodEventsDetected = sum(nGoodEventsDetectedPerClass);
            totalGoodEvents = sum(nTotalGoodEventsPerClass);
            detectionRate = 100 * totalGoodEventsDetected / totalGoodEvents;
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = totalGoodEventsDetected;
            resultsTable{rowIdx,3} = sprintf('%.2f%%',detectionRate);
            
            app.classesResultTable.Data = resultsTable;
        end
        
        %methods
        function buildChainFromUI(app)

            %annotations
            app.annotationsLoader = AnnotationLoader();
            labelMapper = app.labelMappingConfigurator.createLabelMapperFromUI();
            app.currentLabeling = labelMapper.targetLabeling;
            app.annotationsLoader.annotations = app.annotations;
            annotationsAlgorithms = {app.annotationsLoader,labelMapper};
            app.annotationChain = Algorithm.AlgorithmWithSequence(annotationsAlgorithms);
            
            %event detection
            app.fileLoader = FileLoader();
            preprocessor = app.preprocessingConfigurator.createPreprocessingAlgorithmWithUIParameters();
            eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
            Algorithms = {app.fileLoader,PropertyGetter('data')};
            app.labeler = EventsLabeler();
            
            if ~isempty(preprocessor)
                Algorithms{end+1} = preprocessor;
            end
            
            if ~isempty(eventDetector)
                Algorithms{end+1} = eventDetector;
            end
            Algorithms{end+1} = app.labeler;
            
            AlgorithmSequence = Algorithm.AlgorithmWithSequence(Algorithms);
            
            app.arcChain = AlgorithmSequence;
        end
        
        function buildPreprocessingChainFromUI(app)
            app.fileLoaderPreprocessing = FileLoader();
            
            AlgorithmSequence = {app.fileLoaderPreprocessing,PropertyGetter('data')};
            
            preprocessor = app.preprocessingConfigurator.createPreprocessingAlgorithmWithUIParameters();
            
            if ~isempty(preprocessor)
                AlgorithmSequence{end+1} = preprocessor;
            end
            
            preprocessorEventDetection = app.eventDetectorConfigurator.createPreprocessingAlgorithmWithUIParameters();
            if ~isempty(preprocessorEventDetection)
                AlgorithmSequence{end+1} = preprocessorEventDetection;
            end
            
            app.preprocessingChain = Algorithm.AlgorithmWithSequence(AlgorithmSequence);
        end
        
        function computeDetectionResults(app,tolerance)
            if ~isempty(app.eventsPerFile) && ~isempty(app.annotations)
                app.resultsComputer.tolerance = uint32(tolerance);
                    app.resultsPerFile = app.resultsComputer.computeDetectionResults(app.eventsPerFile,...
                    app.processedAnnotations);
            end
        end
        
        function fileIdx = getCurrentFileIdx(app)
            fileIdxStr = app.filesList.Value;
            [~,fileIdx] = ismember(fileIdxStr,app.filesList.Items);
        end
        
        function computeDetectionStatistics(app,selectedFileIdxs)
            aggregatedClassResults = DetectionAggregatedResultsPerClass.computeAggregatedClassStatistics(...
                app.resultsPerFile,app.currentLabeling.numClasses);
            
            app.showResultTables();
            app.updateFilesTable(selectedFileIdxs);
            app.updateClassResultTable(aggregatedClassResults);
        end
        
        function saveEvents(app)
            if ~isempty(app.eventsPerFile)
                currentFileIdx = app.getCurrentFileIdx();
                if ~isempty(currentFileIdx)
                    events = app.eventsPerFile{currentFileIdx};
                    DataLoader.SaveEvents(events,Constants.kDetectedEventsFileName,app.currentLabeling);
                end
            end
        end
        
        function loadFilesList(app)
            app.filesList.Items = app.fileNames;
            app.filesList.Value = app.filesList.Items;
        end
        
        function executeChain(app, selectedFileIdxs)
            selectedFileNames = app.fileNames(selectedFileIdxs);
            nFiles = length(selectedFileIdxs);
            app.eventsPerFile = cell(1,nFiles);
            app.processedAnnotations = repmat(AnnotationSet,1,nFiles);
            for i = 1 : nFiles
                app.fileLoader.fileName = selectedFileNames{i};
                app.annotationsLoader.fileName = selectedFileNames{i};
                app.processedAnnotations(i) = Algorithm.ExecuteChain(app.annotationChain,'start',true);
                app.labeler.manualAnnotations = app.processedAnnotations(i);
                app.eventsPerFile{i} = Algorithm.ExecuteChain(app.arcChain,'start',true);
            end
        end
        
        function executePreprocessingChain(app, selectedFileIdxs)
            selectedFileNames = app.fileNames(selectedFileIdxs);
            
            for i = 1 : length(selectedFileIdxs)
                app.fileLoaderPreprocessing.fileName = selectedFileNames{i};
                app.energiesPerFile{i} = Algorithm.ExecuteChain(app.preprocessingChain,'start',true);
            end
        end
        
        function createEventsPlotter(app,selectedFileIdxs)
            
            selectedFiles = app.filesList.Items(selectedFileIdxs);
            
            if ~isempty(app.eventsPlotter)
                app.eventsPlotter.close();
            end
            
            currentWindowPosition = app.mainFigure.OuterPosition;
            positionX = currentWindowPosition(1) + currentWindowPosition(3);
            windowPosition = [positionX, currentWindowPosition(2)];
            
            app.eventsPlotter = DetectionEventsPlotter(app,app.energiesPerFile,app.resultsPerFile,selectedFiles,windowPosition);
            app.eventsPlotter.labelGrouping = app.currentLabeling;
        end
        
        function loadUIConfigurators(app,labelMappings)
            
            %label mapping
            app.labelMappingConfigurator = LabelMappingConfigurator(...
                labelMappings,...
                app.annotationMappingList,...
                app.annotationMappingTable);
            
            %preprocessing
            signalNames = DataLoader.LoadSignalNames();
            preprocessingAlgorithms = Palette.PreprocessingAlgorithms();
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                signalNames,...
                preprocessingAlgorithms,...
                app.preprocessingSignalsList,...
                app.preprocessingAlgorithmsList,...
                app.preprocessingAlgorithmsVariablesTable);
            
            app.preprocessingSignalsList.Value = app.preprocessingSignalsList.Items{1};
            
            %event detector
            eventDetectionUIElements = EventDetectionConfiguratorUIElements();
            eventDetectionUIElements.preprocessingSignalsList = app.eventDetectionPreprocessingSignalsList;
            eventDetectionUIElements.preprocessingAlgorithmsList = app.eventDetectionPreprocessingAlgorithmsList;
            eventDetectionUIElements.preprocessingAlgorithmsVariablesTable = app.eventDetectionPreprocessingAlgorithmsVariablesTable;
            eventDetectionUIElements.eventDetectionList = app.eventDetectionList;
            eventDetectionUIElements.eventDetectionVariablesTable = app.eventDetectionVariablesTable;
            
            eventDetectors = Palette.EventDetectionAlgorithms();
            
            app.eventDetectorConfigurator = EventDetectionConfigurator(...
                signalNames, preprocessingAlgorithms, eventDetectors, eventDetectionUIElements);
            
        end
        
        %% Setup path
        function setupPath(~)
            fullPath = pwd;
            directories = split(fullPath,'/');
            if(strcmp('3-EventDetectionApp',directories{end}))
                cd ..;
            end
            addpath(genpath('./'));
        end
    end
    
    methods (Access = public)
        function handleEventsPlotterWindowClosed(app)
            delete(app.eventsPlotter);
            app.eventsPlotter = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setupPath();
            
            app.mainFigure.Visible = false;
            close all;
            clear Cache;
            movegui(app.mainFigure,'center');
            app.mainFigure.Visible = true;

            app.resultsComputer = DetectionResultsComputer();
                        
            %load Algorithms
            labelMappings = DataLoader.LoadAllLabelMappings();
            app.loadUIConfigurators(labelMappings);
            
            %load annotations
            defaultLabeling = labelMappings(1).sourceLabeling;
            app.annotations = DataLoader.LoadAllAnnotations(defaultLabeling);
            
            app.fileNames = Helper.ListDataFiles();
            app.loadFilesList();
            
        end

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            
            selectedFileIdxs = app.getSelectedFileIdxs();
            if ~isempty(selectedFileIdxs)
                                               
                app.buildChainFromUI();
                
                try
                    app.executeChain(selectedFileIdxs);
                catch exception
                    warning(Constants.kAlgorithmExecutionFailedWarning);
                    msgText = getReport(exception);
                    warning(msgText);
                end
                
                if ~isempty(app.eventsPerFile)
                    app.buildPreprocessingChainFromUI();
                    selectedFileIdxs = app.getSelectedFileIdxs();
                    app.executePreprocessingChain(selectedFileIdxs);
                end
                
                tolerance = app.getTolerance();
                app.computeDetectionResults(tolerance);
                app.computeDetectionStatistics(selectedFileIdxs);
                
                app.createEventsPlotter(selectedFileIdxs);
            end
        end

        % Button pushed function: saveEventsButton
        function saveEventsButtonPushed(app, event)
            app.saveEvents();
        end

        % Value changed function: selectAllFilesCheckBox
        function selectAllFilesCheckBoxValueChanged(app, event)
            value = app.selectAllFilesCheckBox.Value;
            if value == true
                app.filesList.Value = app.filesList.Items;
            end
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end

        % Close request function: mainFigure
        function mainFigureCloseRequest(app, event)
           if ~isempty(app.eventsPlotter)
               app.eventsPlotter.delegate = [];
               app.eventsPlotter.close();
           end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Event Detection App';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @mainFigureCloseRequest, true);
            app.preprocessingPanel = uipanel(app.mainFigure);
            app.preprocessingPanel.Title = '2-Preprocessing';
            app.preprocessingAlgorithmsVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingAlgorithmsVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingAlgorithmsVariablesTable.RowName = {};
            app.preprocessingAlgorithmsVariablesTable.ColumnEditable = [false true];
            app.preprocessingAlgorithmsList = uilistbox(app.preprocessingPanel);
            app.preprocessingAlgorithmsList.Items = {};
            app.preprocessingAlgorithmsList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.annotationsPanel = uipanel(app.mainFigure);
            app.annotationsPanel.Title = '1b- Annotations';
            app.annotationMappingList = uilistbox(app.annotationsPanel);
            app.annotationMappingList.Items = {};
            app.annotationMappingList.Value = {};
            app.annotationMappingTable = uitable(app.annotationsPanel);
            app.annotationMappingTable.ColumnName = {'Source'; 'Target'};
            app.annotationMappingTable.RowName = {};
            app.annotationMappingTable.ColumnEditable = [false true];
            app.dataPanel = uipanel(app.mainFigure);
            app.dataPanel.Title = '1a-Data';
            app.filesList = uilistbox(app.dataPanel);
            app.filesList.Items = {};
            app.filesList.Multiselect = 'on';
            app.filesList.Value = {};
            app.selectAllFilesCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesCheckBoxValueChanged, true);
            app.selectAllFilesCheckBox.Text = 'select all';
            app.filesResultTable = uitable(app.mainFigure);
            app.filesResultTable.ColumnName = {'File'; 'TP (%)'; 'FP (rate)'; 'F1-S (%)'};
            app.filesResultTable.RowName = {};
            app.classesResultTable = uitable(app.mainFigure);
            app.classesResultTable.ColumnName = {'Class'; 'Detected (#)'; 'Detected (%)'};
            app.classesResultTable.RowName = {};
            app.EventMatchingToleranceLabel = uilabel(app.mainFigure);
            app.EventMatchingToleranceLabel.Text = {'Event Matching'; 'Tolerance'};
            app.toleranceTextBox = uieditfield(app.mainFigure, 'numeric');
            app.toleranceTextBox.Value = 10;
            app.executeButton = uibutton(app.mainFigure, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'Execute';
            app.saveEventsButton = uibutton(app.mainFigure, 'push');
            app.saveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @saveEventsButtonPushed, true);
            app.saveEventsButton.Text = 'Export';
            app.clearCacheButton = uibutton(app.mainFigure, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            app.eventDetectionPanel = uipanel(app.mainFigure);
            app.eventDetectionPanel.Title = 'Event Detection (optional)';
            app.eventDetectionPreprocessingAlgorithmsVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionPreprocessingAlgorithmsVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionPreprocessingAlgorithmsVariablesTable.RowName = {};
            app.eventDetectionPreprocessingAlgorithmsVariablesTable.ColumnEditable = [false true];
            app.eventDetectionPreprocessingAlgorithmsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingAlgorithmsList.Items = {};
            app.eventDetectionPreprocessingAlgorithmsList.Value = {};
            app.eventDetectionPreprocessingSignalsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalsList.Items = {};
            app.eventDetectionPreprocessingSignalsList.Multiselect = 'on';
            app.eventDetectionPreprocessingSignalsList.Value = {};
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true];
            app.eventDetectionList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionList.Items = {};
            app.eventDetectionList.Value = {};
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end