classdef EventDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        perFileResultsText           matlab.ui.control.TextArea
        perClassResultsText          matlab.ui.control.TextArea
        plotAxes                     matlab.ui.control.UIAxes
        preprocessingPanel           matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList     matlab.ui.control.ListBox
        eventDetectionPanel          matlab.ui.container.Panel
        eventDetectorVariablesTable  matlab.ui.control.Table
        eventDetectorList            matlab.ui.control.ListBox
        eventDetectionLabel          matlab.ui.control.Label
        detectButton                 matlab.ui.control.Button
        groupingPanel                matlab.ui.container.Panel
        labelingStrategiesList       matlab.ui.control.ListBox
        groupsLabel                  matlab.ui.control.Label
        groupButton                  matlab.ui.control.Button
        visualizationPanel           matlab.ui.container.Panel
        visualizationFilesList       matlab.ui.control.ListBox
        visualizeButton              matlab.ui.control.Button
        plotToolbar                  matlab.ui.container.ButtonGroup
        zoomButton                   matlab.ui.control.ToggleButton
        panButton                    matlab.ui.control.ToggleButton
        exportPanel                  matlab.ui.container.Panel
        saveEventsButton             matlab.ui.control.Button
        resultsPanel                 matlab.ui.container.Panel
        ToleranceEditFieldLabel      matlab.ui.control.Label
        toleranceTextBox             matlab.ui.control.NumericEditField
        computeButton                matlab.ui.control.Button
        includeLabelsList            matlab.ui.control.ListBox
        showDetectedEventsCheckBox   matlab.ui.control.CheckBox
        showMissedEventsCheckBox     matlab.ui.control.CheckBox
        showFalsePositiveEventsCheckBox  matlab.ui.control.CheckBox
        dataPanel                    matlab.ui.container.Panel
        filesList                    matlab.ui.control.ListBox
        selectAllFilesCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        currentFile;
        
        %data
        dataLoader;
        eventsLoader;
        fileEnergies;
        fileNames;
        annotationsPerFile;
        
        %preprocessing
        preprocessedSignalsLoader;
        
        %detection
        eventDetectorConfigurator;
        eventsPerFile;
        
        %labeling strategies
        labelingConfigurator;
        currentLabelingStrategy;
        
        %results
        resultsComputer;
        resultsPerFile;
        
        %ui state
        showingDetectedEvents;
        showingMissedEvents;
        showingFalsePositiveEvents;
        detectedEventHandles;
        missedEventHandles;
        falsePositiveEventHandles;
        
        %ui plotting
        scrollPanel;
        eventsPlotter;
        preprocessingConfigurator;
        figureHandle;
        energyPlotHandle;
        uiHandles;
        axesHandles;
    end
    
    properties (Access = public, Constant)
        kMaxFileNameLength = 12;
    end
    
    methods (Access = private)
        
        function loadPreprocessedData(app)
            signalComputer = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            app.preprocessedSignalsLoader.preprocessor = signalComputer;
            app.fileEnergies = app.preprocessedSignalsLoader.loadOrCreateData();
        end
    
        function tolerance = getTolerance(app)
            tolerance = app.toleranceTextBox.Value;
            %tolerance = str2double(toleranceStr);
        end
        
        function emptyResultLabels(app)
            app.perFileResultsText.Value = "";
            app.perClassResultsText.Value = "";
        end
        
        function updateFileResultLabels(app)
            text = sprintf("%13s|%8s|%6s\n",'File','Detected','FP');
            for i = 1 : length(app.resultsPerFile)
                fileName = app.fileNames{i};
                maxFileNameLength = min(length(fileName),EventDetectionApp.kMaxFileNameLength);
                fileName = fileName(1:maxFileNameLength);
                fileResults = app.resultsPerFile(i);
                fileResultsStr = fileResults.toString();
                text = sprintf('%s%13s|%s\n',text,fileName,fileResultsStr);
            end
            
            text = sprintf('%s------------------------------\n',text);
            aggregatedFilesResults = DetectionAggregatedResults.DetectionAggregatedResultsWithDetectionResults(app.resultsPerFile);
            aggregatedStatisticsStr = aggregatedFilesResults.toString();
            resultstext = sprintf('%13s|%s','Total',aggregatedStatisticsStr);
            text = sprintf('%s%s',text,resultstext);
            app.perFileResultsText.Value = text;
        end
        
        function updateClassResultLabels(app,classDetectionStatistics)
            text = sprintf("%13s|%s",'Class','Detected');
            
            nGoodEventsDetectedPerClass = classDetectionStatistics.nGoodEventsDetectedPerClass;
            nTotalGoodEventsPerClass = classDetectionStatistics.nGoodEventsPerClass;
            for i = 1 : length(nGoodEventsDetectedPerClass)
                classStr = app.currentLabelingStrategy.classNames{i};
                detectedPerClass = nGoodEventsDetectedPerClass(i);
                if nTotalGoodEventsPerClass(i) == 0
                    detectionRate = 0;
                else
                    detectionRate = 100 * detectedPerClass / nTotalGoodEventsPerClass(i);
                end
                text = sprintf('%s\n%13s|%d(%3.2f%%)',text,classStr,detectedPerClass,detectionRate);
            end
            text = sprintf('%s\n---------------------------',text);
            totalGoodEventsDetected = sum(nGoodEventsDetectedPerClass);
            totalGoodEvents = sum(nTotalGoodEventsPerClass);
            detectionRate = 100 * totalGoodEventsDetected / totalGoodEvents;
            text = sprintf('%s\n%13s|%d(%3.1f%%)',text,'Total',totalGoodEventsDetected,detectionRate);
            
            app.perClassResultsText.Value = text;
        end
        
        function cleanPlot(app)
            cla(app.plotAxes);
            cla(app.plotAxes,'reset');
        end
        
        %methods
        function events = loadEvents(app)
            eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
            app.eventsLoader.eventDetector = eventDetector;
            events = app.eventsLoader.loadOrCreateEvents();
        end
        
        function computeDetectionResults(app,tolerance)
            if ~isempty(app.eventsPerFile) && ~isempty(app.annotationsPerFile)
                app.resultsComputer.tolerance = uint32(tolerance);
                app.resultsPerFile = app.resultsComputer.computeDetectionResults(app.eventsPerFile,app.annotationsPerFile);
            end
        end
        
        function plotEnergy(app)
            energy = app.fileEnergies{app.currentFile};
            app.energyPlotHandle = plot(app.plotAxes,energy);
        end
        
        function plotEvents(app)
            currentFileResults = app.resultsPerFile(app.currentFile);
            
            energy = app.fileEnergies{app.currentFile};
            app.eventsPlotter.symbolColor = 'green';
            app.detectedEventHandles = app.eventsPlotter.plotEvents(app.plotAxes,currentFileResults.goodEvents,energy);
            app.eventsPlotter.symbolColor = [1,0.5,0];
            app.missedEventHandles = app.eventsPlotter.plotEvents(app.plotAxes,currentFileResults.missedEvents,energy);
            app.eventsPlotter.symbolColor = 'red';
            app.falsePositiveEventHandles = app.eventsPlotter.plotEvents(app.plotAxes, currentFileResults.badEvents,energy);
            
            if ~app.showingDetectedEvents
                app.toggleEventsVisibility(app.detectedEventHandles,false);
            end
            
            if ~app.showingMissedEvents
                app.toggleEventsVisibility(app.missedEventHandles,false);
            end
            
            if ~app.showingFalsePositiveEvents
                app.toggleEventsVisibility(app.falsePositiveEventHandles,false);
            end
        end
        
        function toggleEventsVisibility(~,eventHandles,visible)
            for i = 1 : length(eventHandles)
                eventHandle = eventHandles(i);
                eventHandle.setVisible(visible);
            end
        end
        
        function fileIdx = getCurrentFileIdx(app)
            fileIdxStr = app.visualizationFilesList.Value;
            [~,fileIdx] = ismember(fileIdxStr,app.visualizationFilesList.Items);
        end
        
        function computeDetectionStatistics(app)
            nClasses = app.currentLabelingStrategy.numClasses-1;
            aggregatedClassResults = DetectionAggregatedResultsPerClass.computeAggregatedClassStatistics(app.resultsPerFile,nClasses);
            
            app.updateFileResultLabels();
            app.updateClassResultLabels(aggregatedClassResults);
        end
        
        function saveEvents(app)
            if ~isempty(app.eventsPerFile)
                currentFileIdx = app.getCurrentFileIdx();
                events = app.eventsPerFile{currentFileIdx};
                app.dataLoader.saveEvents(events,Constants.kDetectedEventsFileName);
            end
        end
        
        
        function loadFilesList(app)
            app.filesList.Items = app.fileNames;
            app.filesList.Value = app.filesList.Items;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clear ClassesMap;
            app.dataLoader = DataLoader();
            app.eventsLoader = EventsLoader();
            app.resultsComputer = DetectionResultsComputer();
            app.preprocessedSignalsLoader = PreprocessedSignalsLoader();
            app.eventsLoader.preprocessedSignalsLoader = app.preprocessedSignalsLoader;
            app.eventsPlotter = DetectionEventsPlotter();
            
            app.showingDetectedEvents = true;
            app.showingMissedEvents = true;
            app.showingFalsePositiveEvents = true;
            app.currentFile = 1;
            
            %load data
            app.annotationsPerFile = app.dataLoader.loadAllAnnotations();
            
            dataFiles = Helper.listDataFiles();
            app.fileNames = Helper.removeDataFileExtensionForFiles(dataFiles);
            app.loadFilesList();
            
            %load ui configurators
            
            eventDetectors = {SimplePeakDetector, MatlabPeakDetector};
            app.eventDetectorConfigurator = EventDetectorConfigurator(...
                eventDetectors,...
                app.eventDetectorList...
                ,app.eventDetectorVariablesTable);
                 
            app.visualizationFilesList.Items = app.fileNames;
            if(length(app.fileNames) > 1)
                app.visualizationFilesList.Value = app.fileNames{1};
            end
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            app.preprocessingSignalsList.Value = app.preprocessingSignalsList.Items{1};
            
            labelingStrategies = app.dataLoader.loadAllLabelingStrategies();
            app.labelingConfigurator = LabelingConfigurator(...
                app.labelingStrategiesList,labelingStrategies);
            
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            
            app.emptyResultLabels();
            app.cleanPlot();
            
            app.currentLabelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            app.resultsComputer.labelingStrategy = app.currentLabelingStrategy;
            
            app.loadPreprocessedData();
            app.eventsPerFile = app.loadEvents();
            
            tolerance = app.getTolerance();
            app.computeDetectionResults(tolerance);
            app.computeDetectionStatistics();
        end

        % Button pushed function: saveEventsButton
        function saveEventsButtonPushed(app, event)
            app.saveEvents();
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)
            
            if ~isempty(app.eventsPerFile)
                app.eventsPlotter.labelingStrategy = app.currentLabelingStrategy;
                
                app.currentFile = app.getCurrentFileIdx();
                app.cleanPlot();
                app.plotEnergy();
                hold(app.plotAxes,'on');
                app.plotEvents();
            end
        end

        % Value changed function: showDetectedEventsCheckBox
        function showDetectedEventsCheckBoxValueChanged(app, event)
            value = app.showDetectedEventsCheckBox.Value;
            
            app.showingDetectedEvents = value;
            if ~isempty(app.detectedEventHandles)
                app.toggleEventsVisibility(app.detectedEventHandles,app.showingDetectedEvents);
            end
        end

        % Value changed function: showMissedEventsCheckBox
        function showMissedEventsCheckBoxValueChanged(app, event)
            value = app.showMissedEventsCheckBox.Value;
            
            app.showingMissedEvents = value;
            if ~isempty(app.missedEventHandles)
                app.toggleEventsVisibility(app.missedEventHandles,app.showingMissedEvents);
            end
        end

        % Value changed function: showFalsePositiveEventsCheckBox
        function showFalsePositiveEventsCheckBoxValueChanged(app, event)
            value = app.showFalsePositiveEventsCheckBox.Value;
            app.showingFalsePositiveEvents = value;
            if ~isempty(app.falsePositiveEventHandles)
                app.toggleEventsVisibility(app.falsePositiveEventHandles,app.showingFalsePositiveEvents);
            end
        end

        % Selection changed function: plotToolbar
        function plotToolbarSelectionChanged(app, event)
            selectedButton = app.plotToolbar.SelectedObject;
            if(selectedButton == app.zoomButton)
                zoom(app.plotAxes,'on');
            else
                pan(app.plotAxes,'on');
            end
        end

        % Value changed function: selectAllFilesCheckBox
        function selectAllFilesCheckBoxValueChanged(app, event)
            value = app.selectAllFilesCheckBox.Value;
            if value == true
                app.filesList.Value = app.filesList.Items;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.perFileResultsText = uitextarea(app.UIFigure);
            app.perClassResultsText = uitextarea(app.UIFigure);
            app.plotAxes = uiaxes(app.UIFigure);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.eventDetectionLabel = uilabel(app.eventDetectionPanel);
            app.eventDetectionLabel.Text = '';
            app.detectButton = uibutton(app.eventDetectionPanel, 'push');
            app.detectButton.Text = 'Detect';
            app.groupingPanel = uipanel(app.UIFigure);
            app.groupingPanel.Title = 'Grouping';
            app.labelingStrategiesList = uilistbox(app.groupingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.groupsLabel = uilabel(app.groupingPanel);
            app.groupsLabel.Text = '';
            app.groupButton = uibutton(app.groupingPanel, 'push');
            app.groupButton.Text = 'Group';
            app.visualizationPanel = uipanel(app.UIFigure);
            app.visualizationPanel.Title = 'Visualization';
            app.visualizationFilesList = uilistbox(app.visualizationPanel);
            app.visualizationFilesList.Items = {};
            app.visualizationFilesList.Value = {};
            app.visualizeButton = uibutton(app.visualizationPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.plotToolbar = uibuttongroup(app.UIFigure);
            app.plotToolbar.SelectionChangedFcn = createCallbackFcn(app, @plotToolbarSelectionChanged, true);
            app.zoomButton = uitogglebutton(app.plotToolbar);
            app.zoomButton.Text = 'Zoom';
            app.panButton = uitogglebutton(app.plotToolbar);
            app.panButton.Text = 'Pan';
            app.panButton.Value = true;
            app.exportPanel = uipanel(app.UIFigure);
            app.exportPanel.Title = 'Export';
            app.saveEventsButton = uibutton(app.exportPanel, 'push');
            app.saveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @saveEventsButtonPushed, true);
            app.saveEventsButton.Text = 'Export';
            app.resultsPanel = uipanel(app.UIFigure);
            app.resultsPanel.Title = 'Results';
            app.ToleranceEditFieldLabel = uilabel(app.resultsPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.toleranceTextBox = uieditfield(app.resultsPanel, 'numeric');
            app.toleranceTextBox.Value = 10;
            app.computeButton = uibutton(app.resultsPanel, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'Compute';
            app.includeLabelsList = uilistbox(app.resultsPanel);
            app.includeLabelsList.Items = {};
            app.includeLabelsList.Multiselect = 'on';
            app.includeLabelsList.Value = {};
            app.showDetectedEventsCheckBox = uicheckbox(app.UIFigure);
            app.showDetectedEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showDetectedEventsCheckBoxValueChanged, true);
            app.showDetectedEventsCheckBox.Text = 'show detected';
            app.showDetectedEventsCheckBox.Value = true;
            app.showMissedEventsCheckBox = uicheckbox(app.UIFigure);
            app.showMissedEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showMissedEventsCheckBoxValueChanged, true);
            app.showMissedEventsCheckBox.Text = 'show missed';
            app.showMissedEventsCheckBox.Value = true;
            app.showFalsePositiveEventsCheckBox = uicheckbox(app.UIFigure);
            app.showFalsePositiveEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showFalsePositiveEventsCheckBoxValueChanged, true);
            app.showFalsePositiveEventsCheckBox.Text = 'show false positives';
            app.showFalsePositiveEventsCheckBox.Value = true;
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = 'Data';
            app.filesList = uilistbox(app.dataPanel);
            app.filesList.Items = {};
            app.filesList.Multiselect = 'on';
            app.filesList.Value = {};
            app.selectAllFilesCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesCheckBoxValueChanged, true);
            app.selectAllFilesCheckBox.Text = 'select all';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EventDetectionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end