classdef AssessmentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                      matlab.ui.Figure
        mainTabGroup                    matlab.ui.container.TabGroup
        AlgorithmDesignTab              matlab.ui.container.Tab
        algorithmFromUserInterfacePanel  matlab.ui.container.Panel
        segmentationPanel               matlab.ui.container.Panel
        segmentationVariablesTable      matlab.ui.control.Table
        segmentationList                matlab.ui.control.ListBox
        eventDetectionPanel             matlab.ui.container.Panel
        eventDetectionPreprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        eventDetectionPreprocessingSignalComputerList  matlab.ui.control.ListBox
        eventDetectionPreprocessingSignalsList  matlab.ui.control.ListBox
        eventDetectionVariablesTable    matlab.ui.control.Table
        eventDetectionList              matlab.ui.control.ListBox
        segmentsSourceButtonGroup       matlab.ui.container.ButtonGroup
        manualSegmentationRadio         matlab.ui.control.RadioButton
        automaticSegmentationRadio      matlab.ui.control.RadioButton
        featureExtractionPanel          matlab.ui.container.Panel
        shouldNormalizeFeaturesCheckBox  matlab.ui.control.CheckBox
        featuresSourceButtonGroup       matlab.ui.container.ButtonGroup
        featuresSourceFromFileRadio     matlab.ui.control.RadioButton
        featuresSourceManuallyRadio     matlab.ui.control.RadioButton
        loadedFeatureExtractorsList     matlab.ui.control.ListBox
        manualFeatureExtractionPanel    matlab.ui.container.Panel
        defaultFeaturesList             matlab.ui.control.ListBox
        selectedFeaturesList            matlab.ui.control.ListBox
        addFeatureButton                matlab.ui.control.Button
        removeFeatureButton             matlab.ui.control.Button
        StartEditFieldLabel             matlab.ui.control.Label
        featureStartRangeEditText       matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        featureEndRangeEditText         matlab.ui.control.NumericEditField
        featureFullSegmentCheckBox      matlab.ui.control.CheckBox
        AxesLabel                       matlab.ui.control.Label
        featureAxisEditText             matlab.ui.control.EditField
        saveFeatures                    matlab.ui.control.Button
        manualAnnotationsTypePanel      matlab.ui.container.ButtonGroup
        includeEventsCheckBox           matlab.ui.control.CheckBox
        includeRangesCheckBox           matlab.ui.control.CheckBox
        includeLabelsPanel              matlab.ui.container.Panel
        classifiersList                 matlab.ui.control.ListBox
        classifiersVariablesTable       matlab.ui.control.Table
        featureSelectionPanel           matlab.ui.container.Panel
        findFeatures                    matlab.ui.control.Button
        printFeatures                   matlab.ui.control.Button
        selectFeaturesButton            matlab.ui.control.Button
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        nFeaturesEditField              matlab.ui.control.NumericEditField
        featuresTextBox                 matlab.ui.control.TextArea
        labelingPanel                   matlab.ui.container.Panel
        labelingStrategiesList          matlab.ui.control.ListBox
        labelingVariablesTable          matlab.ui.control.Table
        validationPanel                 matlab.ui.container.Panel
        testFilesList                   matlab.ui.control.ListBox
        trainFilesList                  matlab.ui.control.ListBox
        moveFileRightButton             matlab.ui.control.Button
        moveFileLeftButton              matlab.ui.control.Button
        selectAllTrainingCheckBox       matlab.ui.control.CheckBox
        selectAllTestingCheckBox        matlab.ui.control.CheckBox
        validationButtonGroup           matlab.ui.container.ButtonGroup
        holdoutRadio                    matlab.ui.control.RadioButton
        crossValidationRadio            matlab.ui.control.RadioButton
        postProcessingPanel             matlab.ui.container.Panel
        postprocessingList              matlab.ui.control.ListBox
        postprocessingVariablesTable    matlab.ui.control.Table
        annotationsPanelPostProcessing  matlab.ui.container.Panel
        annotationMappingListPostprocessing  matlab.ui.control.ListBox
        annotationMappingTablePostProcessing  matlab.ui.control.Table
        preprocessingPanel              matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList        matlab.ui.control.ListBox
        algorithmSourceButtonGroup      matlab.ui.container.ButtonGroup
        algorithmFromUserInterfaceButton  matlab.ui.control.RadioButton
        algorithmFromFileButton         matlab.ui.control.RadioButton
        algorithmFromFilePanel          matlab.ui.container.Panel
        visualizeARCButton              matlab.ui.control.Button
        executeChainFile                matlab.ui.control.Button
        chainFilesList                  matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        clearCacheButton                matlab.ui.control.Button
        executeButton                   matlab.ui.control.Button
        filesPanel                      matlab.ui.container.Panel
        inputFilesList                  matlab.ui.control.ListBox
        selectAllInputFilesList         matlab.ui.control.CheckBox
        signalsList                     matlab.ui.control.ListBox
        annotationsPanel                matlab.ui.container.Panel
        annotationMappingList           matlab.ui.control.ListBox
        annotationMappingTable          matlab.ui.control.Table
        algorithmAssessmentTab          matlab.ui.container.Tab
        recognitionPerformanceMetricsPanel  matlab.ui.container.Panel
        performanceMetricsPerFileTable  matlab.ui.control.Table
        recognitionPerformanceMetricsPerClassPanel  matlab.ui.container.Panel
        performanceMetricsPerClassTable  matlab.ui.control.Table
        confusionMatrixAxes             matlab.ui.control.UIAxes
        computationalPerformanceMetricsPerClassPanel  matlab.ui.container.Panel
        computationalPerformanceMetricsTable  matlab.ui.control.Table
        algorithmAssessmentDetailViewButton  matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        exportPanel                     matlab.ui.container.Panel
        exportNormalisationValuesCheckBox  matlab.ui.control.CheckBox
        exportFormatGroup               matlab.ui.container.ButtonGroup
        exportMatlabFormatCheckBox      matlab.ui.control.RadioButton
        exportTextFormatCheckBox        matlab.ui.control.RadioButton
        exportLabelTypeGroup            matlab.ui.container.ButtonGroup
        exportTextLabelsRadio           matlab.ui.control.RadioButton
        exportNumberLabelsRadio         matlab.ui.control.RadioButton
        exportButton                    matlab.ui.control.Button
        exportTablesList                matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
        % data
        dataLoader;
        fileNames;
        signalNames;
        filesMap;
        annotations;
        processedAnnotations;
        fileLoader;
        loadedARChainFiles;
        segmentsPerFile;
        totalNumSamples;
        
        
        %arc chains
        annotationChain;
        segmentationChain;
        featureExtractionChain;
        classificationChain;
        postProcessingAlgorithm;
        segmentationAlgorithm;
        
        %configurators
        preprocessingConfigurator;
        eventDetectorConfigurator;
        segmentationConfigurator;
        labelingConfigurator;
        labelMappingConfigurator;
        featureExtractionConfigurator;
        classificationConfigurator;
        validationConfigurator;
        postprocessingConfigurator;
        
        %helpers
        annotationsLoader;
        labeler;
        currentLabeling;
        featureSelector;
        featureNormalizer;
        tableExporter;
        confusionMatrixPlotter;
        manualSegmentation;
        
        %state
        isManualMode;
        
        %results
        featureTables;
        classificationResults;
        generatedTableNames;
        detailedClassificationResults;
        segmentationMetrics;
        featureExtractionMetrics;
        classificationMetrics;
        
        %results viewer
        detailedClassificationViewer = [];
        validationWaitBar = [];
    end
    
    methods (Access = private)
        
        function loadConfigurators(app, labelMappings)
            
            %label mapping
            app.labelMappingConfigurator = LabelMappingConfigurator(...
                labelMappings,...
                app.annotationMappingList,...
                app.annotationMappingTable, app);
            
            %preprocessing
            preprocessingComputers = Palette.PreprocessingComputers();
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                app.signalNames,...
                preprocessingComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            eventDetectionUIElements = EventDetectionConfiguratorUIElements();
            eventDetectionUIElements.preprocessingSignalsList = app.eventDetectionPreprocessingSignalsList;
            eventDetectionUIElements.preprocessingSignalComputerList = app.eventDetectionPreprocessingSignalComputerList;
            eventDetectionUIElements.preprocessingSignalComputerVariablesTable = app.eventDetectionPreprocessingSignalComputerVariablesTable;
            eventDetectionUIElements.eventDetectionList = app.eventDetectionList;
            eventDetectionUIElements.eventDetectionVariablesTable = app.eventDetectionVariablesTable;
            
            eventDetectors = Palette.EventDetectionComputers();
            
            app.eventDetectorConfigurator = EventDetectionConfigurator(...
                app.signalNames, preprocessingComputers, eventDetectors, eventDetectionUIElements);
            
            segmentationStrategies = {app.manualSegmentation};
            
            app.segmentationConfigurator = ComputerConfigurator(...
                segmentationStrategies,...
                app.segmentationList,...
                app.segmentationVariablesTable);
            
            app.segmentationConfigurator = ComputerConfigurator(...
                segmentationStrategies,...
                app.segmentationList,...
                app.segmentationVariablesTable);
            
            app.labelingConfigurator = ComputerConfigurator(...
                Palette.LabelingStrategyComputers(),...
                app.labelingStrategiesList,...
                app.labelingVariablesTable);
            
            defaultFeatureExtractors = FeatureExtractor.DefaultFeatures();
            
            app.loadedARChainFiles = Helper.listARChainFiles();
            
            %feature extraction
            featureExtractorUIElements = FeatureExtractionConfiguratorUIElements();            
            featureExtractorUIElements.defaultFeaturesList = app.defaultFeaturesList;
            featureExtractorUIElements.addFeatureButton = app.addFeatureButton;
            featureExtractorUIElements.removeFeatureButton = app.removeFeatureButton;
            featureExtractorUIElements.selectedFeaturesList = app.selectedFeaturesList;
            featureExtractorUIElements.featureStartRangeEditText = app.featureStartRangeEditText;
            featureExtractorUIElements.featureEndRangeEditText = app.featureEndRangeEditText;
            featureExtractorUIElements.featureFullSegmentCheckBox = app.featureFullSegmentCheckBox;
            featureExtractorUIElements.featureAxisEditText = app.featureAxisEditText;
            featureExtractorUIElements.loadedFeatureExtractorsList = app.loadedFeatureExtractorsList;
            featureExtractorUIElements.featuresSourceButtonGroup = app.featuresSourceButtonGroup;
            featureExtractorUIElements.featuresSourceFromFileRadio = app.featuresSourceFromFileRadio;
            featureExtractorUIElements.featuresSourceManuallyRadio = app.featuresSourceManuallyRadio;
            featureExtractorUIElements.manualFeatureExtractionPanel = app.manualFeatureExtractionPanel;
            
            app.featureExtractionConfigurator = FeatureExtractionConfigurator(...
                defaultFeatureExtractors,...
                app.loadedARChainFiles,...
                featureExtractorUIElements);
            
            %validation
            validationUIElements = ValidationConfiguratorUIElements();
            validationUIElements.validationTypeRadioButtonGroup = app.validationButtonGroup;
            validationUIElements.holdoutRadio = app.holdoutRadio;
            validationUIElements.crossValidationRadio = app.crossValidationRadio;
            validationUIElements.selectAllTrainingCheckBox = app.selectAllTrainingCheckBox;
            validationUIElements.selectAllTestingCheckBox = app.selectAllTestingCheckBox;
            validationUIElements.moveRightButton = app.moveFileRightButton;
            validationUIElements.moveLeftButton = app.moveFileLeftButton;
            validationUIElements.trainFilesList = app.trainFilesList;
            validationUIElements.testFilesList = app.testFilesList;
            
            app.validationConfigurator = ValidationConfigurator(validationUIElements);
            
            %classification
            classificationAlgorithms = Palette.ClassificationComputers();
            
            app.classificationConfigurator = ComputerConfigurator(...
                classificationAlgorithms,...
                app.classifiersList...
                ,app.classifiersVariablesTable);
            
            
            %post processing
            postprocessingUIElements = PostProcessingConfiguratorUIElements(...
                app.postprocessingList, app.postprocessingVariablesTable,...
                app.annotationsPanelPostProcessing,...
                app.annotationMappingListPostprocessing,...
                app.annotationMappingTablePostProcessing);
            
            postprocessingAlgorithms = Palette.PostprocessingComputers();
            app.currentLabeling = app.labelMappingConfigurator.createTargetLabeling();
            app.postprocessingConfigurator = PostprocessingConfigurator(postprocessingAlgorithms,...
                postprocessingUIElements, app.currentLabeling);
            
            app.updateEventDetectionTablesVisibility();
        end
        
        function loadFilesMap(app)
            for i = 1 : length(app.fileNames)
                fileName = app.fileNames{i};
                app.filesMap(fileName) = i;
            end
        end
        
        function populateFilesList(app)
            app.inputFilesList.Items = app.fileNames;
            app.inputFilesList.Value = app.inputFilesList.Items;
        end
        
        function populateSignalsList(app)
            app.signalsList.Items = app.signalNames;
            app.signalsList.Value = app.signalsList.Items;
        end
        
        function populateChainFilesList(app)
            app.chainFilesList.Items = app.loadedARChainFiles;
        end
        
        function populateValidationFilesList(app)
            app.trainFilesList.Items = app.inputFilesList;
            app.trainFilesList.Value = app.trainFilesList.Items;
            app.testFilesList.Items = {};
        end
        
        function selectedFilesStr = popSelectedFilesFromList(~,list)
            selectedFilesStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFilesStr,list.Items);
            listIndices = setdiff(1:length(list.Items),selectedFileIdxs);
            list.Items = list.Items(listIndices);
        end
        
        function list = pushFilesToList(~,files,list)
            nItems = length(list.Items);
            nFiles = length(files);
            list.Items(nItems + 1:nItems + nFiles) = files;
        end
        
        function table = createTableWithFilesInList(app,list)
            selectedFileStr = list.Value;
            [~,selectedFileIdxs] = ismember(selectedFileStr,list.Items);
            
            table = [];
            if ~isempty(selectedFileIdxs)
                fileIdxs = app.getGlobalFileIdxs(list,selectedFileIdxs);
                table = app.tableSet.mergedTableForIndices(fileIdxs);
            end
        end
        
        function fileIdxs = getGlobalFileIdxs(app,list,inFileIdxs)
            nFileIdxs = length(inFileIdxs);
            fileIdxs = zeros(1,nFileIdxs);
            for i = 1 : nFileIdxs
                fileIdx = inFileIdxs(i);
                fileName = list.Items{fileIdx};
                fileIdx = app.filesMap(fileName);
                fileIdxs(i) = fileIdx;
            end
        end
        
        function plotConfusionMatrix(app,confusionMatrix)
            app.confusionMatrixPlotter.clearPlot();
            app.confusionMatrixAxes.Visible = true;
            app.confusionMatrixPlotter.plotConfusionMatrix(confusionMatrix);
        end
        
        function plotAggregatedClassificationResults(app,aggregatedClassificationResults)
            title(app.confusionMatrixAxes,sprintf('Accuracy: %.2f%% Recall: %.2f%% Precision: %.2f%% F1-score: %.2f%%',...
                100 * aggregatedClassificationResults.accuracy, 100 * aggregatedClassificationResults.recall,...
                100 * aggregatedClassificationResults.precision, 100 * aggregatedClassificationResults.f1Score));
            
        end
        
        function exportTable(app,table,fileName)
            if ~isempty(table)
                
                previousLabels = table.label;
                
                if app.exportTextLabelsRadio.Value == 1
                    labelGrouping = app.labelMappingConfigurator.getcurrentLabeling();
                    
                    includeLabelIdxs = app.getIncludeLabelIdxs();
                    
                    classNames = [labelGrouping.classNames(includeLabelIdxs), Labeling.kNullClassStr];
                    table.label (table.label  == 0) = length(classNames);
                    table.label = classNames(table.label)';
                else
                    table.label = table.label;
                end
                
                if app.exportMatlabFormatCheckBox.Value == 1
                    t = table.table;
                    save(fileName,'t');
                else
                    app.tableExporter.exportTable(table.table,fileName);
                end
                
                table.label = previousLabels;
            end
        end
        
        function exportNormalisationValues(app)
            if ~isempty(app.featureNormalizer.means) && ~isempty(app.featureTables) && app.featureTables.nTables > 0
                means = app.featureNormalizer.means;
                stds = app.featureNormalizer.stds;
                normalisationValues = array2table([means;stds]);
                firstTable = app.featureTables.tables{1};
                columnNames = firstTable.columnNames;
                normalisationValues.Properties.VariableNames = columnNames(1:end-1);
                normalisationValues.Properties.RowNames = {'means','deviations'};
                app.tableExporter.writeRowNames = true;
                app.tableExporter.exportTable(normalisationValues,Constants.kNormalisationFileName);
                app.tableExporter.writeRowNames = false;
            end
        end
        
        %% UI
        function selectedIdxs = getSelectedIdxsForList(~, uiList)
            selectedStrs = uiList.Value;
            [~,selectedIdxs] = ismember(selectedStrs,uiList.Items);
        end
        
        function updatePreprocessingPanelVisibility(app)
            if app.isManualMode
                app.preprocessingPanel.Visible = 'Off';
            else
                app.preprocessingPanel.Visible = 'On';
            end
        end
        
        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.manualAnnotationsTypePanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'Off';
                app.eventDetectionPreprocessingSignalComputerList.Visible = 'Off';
            else
                app.manualAnnotationsTypePanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'On';
                app.eventDetectionPreprocessingSignalComputerList.Visible = 'On';
            end
        end
        
        function updateLabelingPanelVisibility(app)
            if app.isManualMode
                app.labelingPanel.Visible = 'Off';
            else
                app.labelingPanel.Visible = 'On';
            end
        end
        
        function idx = getSelectedSegmentLoaderIdx(app)
            switch app.segmentationGroup.Selectedappect
                case app.manualSegmentationRadio
                    idx = 1;
                case app.simplifiedSegmentationRadio
                    idx = 2;
            end
        end
        
        function nFeatures = getNFeatures(app)
            nFeatures = app.nFeaturesEditField.Value;
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function labelIdxsBoolean = getIncludeLabelIdxsBoolean(app)
            labelIdxsBoolean = ones(1,app.currentLabeling.numClasses);
        end
        
        function labelIdxs = getIncludeLabelIdxs(app)
            labelIdxsBoolean = app.getIncludeLabelIdxsBoolean();
            labelIdxs = 1:length(labelIdxsBoolean);
            labelIdxs = labelIdxs(labelIdxsBoolean);
        end
        
        function labelStr = labelForTable(~,table,tableTypeStr,labelGrouping)
            
            if isempty(table)
                labelStr = sprintf('%s table:\n empty',tableTypeStr);
            else
                labelStr = sprintf('%s table:\n %dx%d\n (%s)',tableTypeStr,table.height,...
                    table.width,labelGrouping.name);
            end
        end
        
        function loadIncludeLabelsList(app,labelGrouping)
            app.includeLabelsList.Items = labelGrouping.classNames;
            app.includeLabelsList.Value = app.includeLabelsList.Items;
        end
        
        function indices = getSelectedSignalIndices(app)
            [~,indices] = ismember(app.signalsList.Value,app.signalsList.Items);
        end
        
        function buildAnnotationChain(app)
            app.annotationsLoader = AnnotationLoader();
            labelMapper = app.labelMappingConfigurator.createLabelMapperFromUI();
            
            app.currentLabeling = labelMapper.targetLabeling;
            app.annotationsLoader.annotations = app.annotations;
            annotationsComputers = {app.annotationsLoader,labelMapper};
            app.annotationChain = Computer.ComputerWithSequence(annotationsComputers);
        end
        
        function buildManualSegmentationChain(app)
            app.segmentationAlgorithm = app.segmentationConfigurator.createComputerWithUIParameters();
            app.fileLoader = FileLoader();
            app.fileLoader.selectedSignalIndices = app.getSelectedSignalIndices();
            app.segmentationAlgorithm.includeEvents = app.getIncludeEvents();
            app.segmentationAlgorithm.includeRanges = app.getIncludeRanges();
            app.segmentationChain = Computer.ComputerWithSequence({app.fileLoader,app.segmentationAlgorithm});
        end
        
        function buildAutomaticSegmentationChain(app)
            app.fileLoader = FileLoader();
            app.fileLoader.selectedSignalIndices = app.getSelectedSignalIndices();
            app.segmentationAlgorithm = app.segmentationConfigurator.createComputerWithUIParameters();
            preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
            app.labeler = app.labelingConfigurator.createComputerWithUIParameters();
            propertyGetter = PropertyGetter('data');
            
            computerSequence = {app.fileLoader,propertyGetter};
            
            if ~isempty(preprocessor)
                computerSequence{end+1} = preprocessor;
            end
            
            if ~isempty(eventDetector)
                computerSequence{end+1} = eventDetector;
            end
            
            computerSequence{end+1} = app.segmentationAlgorithm;
            computerSequence{end+1} = app.labeler;
            
            app.segmentationChain = Computer.ComputerWithSequence(computerSequence);
        end
        
        function buildSegmentationChain(app)
            
            app.buildAnnotationChain();
            
            if app.isManualMode
                app.buildManualSegmentationChain();
            else
                app.buildAutomaticSegmentationChain();
            end
        end
        
        function buildFeatureExtractionChain(app)
            featureExtractor = app.featureExtractionConfigurator.createFeatureExtractorWithUIParameters();
            tableRowSelector = TableRowSelector(app.getIncludeLabelIdxsBoolean());
            app.featureExtractionChain = Computer.ComputerWithSequence({featureExtractor,tableRowSelector});
        end
        
        function buildClassificationChain(app)
            validator = app.validationConfigurator.createValidatorWithUIParameters();
            validator.shouldNormalizeFeatures = app.shouldNormalizeFeaturesCheckBox.Value;
            validator.progressNotificationDelegate = app;
            validator.classifier = app.classificationConfigurator.createComputerWithUIParameters();
            app.postProcessingAlgorithm = app.postprocessingConfigurator.createComputerWithUIParameters();

            app.classificationChain = Computer.ComputerWithSequence({validator,app.postProcessingAlgorithm});
        end
        
        function addMetrics(~, metrics1, metrics2)
            metrics1.flops = metrics1.flops + metrics2.flops;
            metrics1.memory = max(metrics1.memory + metrics2.memory);
            metrics1.outputSize = metrics1.outputSize + metrics2.outputSize;
        end
        
        function normalizeMetrics(app, metrics, nFiles)
            metrics.flops = metrics.flops / app.totalNumSamples;
            metrics.memory = metrics.memory / nFiles;
            metrics.outputSize = metrics.outputSize / nFiles;
        end
        
        function segmentationSucceeded = executeSegmentationAndFeatureExtractionChain(app,uiList)
            selectedFileIdxs = app.getSelectedIdxsForList(uiList);
            selectedFileNames = uiList.Items(selectedFileIdxs);
            
            nFiles = length(selectedFileNames);
            
            tables = repmat(Table,1,nFiles);
            app.segmentsPerFile = cell(1,nFiles);
            
            waitBar = waitbar(0,'Executing Chain');
            
            app.segmentationMetrics = Metric();
            app.featureExtractionMetrics = Metric();
            
            app.totalNumSamples = 0;
            segmentationSucceeded = true;
            
            app.processedAnnotations = repmat(AnnotationSet,1,nFiles);
            try
                for i = 1 : nFiles
                    waitBarMsg = sprintf('Executing Chain for file %s ...',app.fileLoader.fileName);
                    waitbar(i/nFiles,waitBar,waitBarMsg);
                    
                    fileName = selectedFileNames{i};
                    app.fileLoader.fileName = fileName;
                    app.annotationsLoader.fileName = fileName;

                    [app.processedAnnotations(i),~,annotationChainString] = Computer.ExecuteChain(app.annotationChain,'start',true);
                    
                    if app.isManualMode
                        app.segmentationAlgorithm.manualAnnotations = app.processedAnnotations(i);
                    else
                        app.labeler.manualAnnotations = app.processedAnnotations(i);
                    end

                    [app.segmentsPerFile{i}, currentSegmentationMetric, segmentationChainString] = ...
                        Computer.ExecuteChain(app.segmentationChain,'start',true,annotationChainString,app.segmentationAlgorithm);
                    
                    [tables(i),currentFeatureExtractionMetric] = ...
                        Computer.ExecuteChain(app.featureExtractionChain,app.segmentsPerFile{i},true,segmentationChainString);
                    
                    %loads the file because the execution chain might not
                    %have loaded it
                    file = app.fileLoader.loadFile();
                    app.totalNumSamples = app.totalNumSamples + file.numRows;
                    tables(i).file = file;
                    tables(i).classNames = app.currentLabeling.classNames;
                    
                    app.addMetrics(app.segmentationMetrics ,currentSegmentationMetric);
                    app.addMetrics(app.featureExtractionMetrics,currentFeatureExtractionMetric);
                end
                
                app.normalizeMetrics(app.segmentationMetrics,nFiles);
                app.normalizeMetrics(app.featureExtractionMetrics,nFiles);
                
                app.featureTables = TableSet(tables);

            catch exception
                warning(Constants.kAlgorithmExecutionFailedWarning);
                msgText = getReport(exception);
                warning(msgText);
                segmentationSucceeded = false;
            end
            close(waitBar);
        end
        
        function classificationSucceeded = executeClassificationChain(app)
            classificationSucceeded = true;
            try
                [app.classificationResults, app.classificationMetrics] = Computer.ExecuteChain(app.classificationChain,app.featureTables,false);
            catch exception
                warning(Constants.kAlgorithmExecutionFailedWarning);
                msgText = getReport(exception);
                warning(msgText);
                if ~isempty(app.validationWaitBar)
                    close(app.validationWaitBar);
                    app.mainFigure.Visible = 'off';
                    app.mainFigure.Visible = 'on';
                    app.validationWaitBar = [];
                end
                classificationSucceeded = false;
            end
        end
        
       
        function classificationSucceeded = executeChainFromFile(app)
            selectedFileIdxs = app.getSelectedIdxsForList(app.inputFilesList);
            selectedFileNames = app.inputFilesList.Items(selectedFileIdxs);
            
            nFiles = length(selectedFileNames);
            
            waitBar = waitbar(0,'Executing Chain');
            
            app.segmentationMetrics = Metric();
            app.featureExtractionMetrics = Metric();
            
            app.totalNumSamples = 0;
            
            app.processedAnnotations = repmat(AnnotationSet,1,nFiles);
            
            classificationSucceeded = true;
            try
                for i = 1 : nFiles
                    waitBarMsg = sprintf('Executing Chain for file %s ...',app.fileLoader.fileName);
                    waitbar(i/nFiles,waitBar,waitBarMsg);
                    
                    fileName = selectedFileNames{i};
                    app.fileLoader.fileName = fileName;
                    app.annotationsLoader.fileName = fileName;
                    
                    [app.processedAnnotations(i),~,annotationChainString] = Computer.ExecuteChain(app.annotationChain,'start',true);
                    
                    app.labeler = Helper.FindLabelerInUserChain(app.classificationChain);
                    app.labeler.manualAnnotations = app.processedAnnotations(i);
                    
                    [app.classificationResults, app.classificationMetrics] = Computer.ExecuteChain(app.classificationChain,'',false,annotationChainString);
                end
                
            catch exception
                warning(Constants.kAlgorithmExecutionFailedWarning);
                msgText = getReport(exception);
                warning(msgText);
                if ~isempty(app.validationWaitBar)
                    close(app.validationWaitBar);
                    app.mainFigure.Visible = 'off';
                    app.mainFigure.Visible = 'on';
                    app.validationWaitBar = [];
                end
                classificationSucceeded = false;
            end
        end
        
        function detailedClassificationResults = createDetailedClassificationResults(app)
            testFileIndices = app.validationConfigurator.getTestFileNameIdxs();
            nResults = length(testFileIndices);
            detailedClassificationResults = repmat(AssessmentDetailedClassificationResult,1,nResults);
            
            for i = 1 : nResults
                testFileIdx = testFileIndices(i);
                annotation = app.processedAnnotations(i);
                
                detailedClassificationResults(i) = AssessmentDetailedClassificationResult(...
                    annotation,...
                    app.segmentsPerFile{testFileIdx},...
                    app.classificationResults(i),...
                    app.postProcessingAlgorithm);
            end
        end
        
        function result = isValidInputFilesConfiguration(app)
            
            if isempty(app.inputFilesList.Value)
                result = false;
            else
                result = true;
            end
        end
        
        function updateExportFeatureTablesList(app)
            
            tablesStr = cell(1,app.featureTables.nTables);
            for i = 1 : app.featureTables.nTables
                table = app.featureTables.tables(i);
                fileName = app.generatedTableNames{i};
                tablesStr{i} = sprintf('%s %dx%d',fileName,table.height,table.width);
            end
            
            app.exportTablesList.Items = tablesStr;
        end
        
        function idx = idxsOfGeneratedTableWithNames(app,tableName)
            [~,idx] = ismember(tableName,app.generatedTableNames);
        end
        
        function tables = getGeneratedTableWithNames(app,tableNames)
            tableIdxs = app.idxsOfGeneratedTableWithNames(tableNames);
            tables = app.featureTables.mergedTableForIndices(tableIdxs);
        end
        
        function updateSelectedFeatures(app)
            app.featuresTextBox.Value = Helper.arrayToString(app.featureSelector.selectedFeatureIdxs,', ');
            app.nFeaturesEditField.Value = length(app.featureSelector.selectedFeatureIdxs);
        end
        function updateRecognitionPerformanceMetricsTablePerFile(app,detailedClassificationResults)
            nFiles = length(detailedClassificationResults);

            cellArray = cell(nFiles,5);
            for i= 1 : nFiles
                detailedClassificationResult = detailedClassificationResults(i);
                
                aggregatedClassificationResults = ...
                AssessmentAggregatedClassificationResults.CreateClassificationResultsFromDetailed(detailedClassificationResult);
                
                cellArray{i,1} = detailedClassificationResult.fileName;
                cellArray{i,2} = sprintf('%.1f%%',100 * aggregatedClassificationResults.accuracy);
                cellArray{i,3} = sprintf('%.1f%%',100 * aggregatedClassificationResults.precision);
                cellArray{i,4} = sprintf('%.1f%%',100 * aggregatedClassificationResults.recall);
                cellArray{i,5} = sprintf('%.1f%%',100 * aggregatedClassificationResults.f1Score);
            end
            
            app.performanceMetricsPerFileTable.Data = cellArray;
        end
        
        function updateRecognitionPerformanceMetricsTablePerClass(app,aggregatedClassificationResults)
            nClasses = length(aggregatedClassificationResults.precisionPerClass);
            
            classNames = aggregatedClassificationResults.classNames;
            cellArray = cell(nClasses,3);
            for i= 1 : nClasses
                cellArray{i,1} = classNames{i};
                cellArray{i,2} = sprintf('%.1f%%',100 * aggregatedClassificationResults.precisionPerClass(i));
                cellArray{i,3} = sprintf('%.1f%%',100 * aggregatedClassificationResults.recallPerClass(i));
            end
            
            app.performanceMetricsPerClassTable.Data = cellArray;
        end
        
        function confusionMatrix = createConfusionMatrix(~,detailedClassificationResults)
            if ~isempty(detailedClassificationResults)
                truthClassesCells = arrayfun(@(x) x.getAllTruthLabels,detailedClassificationResults,'UniformOutput',false);
                truthClasses = cat(1,truthClassesCells{:});
                
                predictedClassCells = arrayfun(@(x) x.getAllPredictedLabels,detailedClassificationResults,'UniformOutput',false);
                predictedClasses = cat(1,predictedClassCells{:});
                
                classNames = detailedClassificationResults(1).classificationResult.classNames;
                labelIdxs = 1:length(classNames);
                confusionMatrix = ConfusionMatrix(truthClasses,predictedClasses,labelIdxs,classNames);
            end
        end
        
        function createDetaileClassificationResultsViewer(app,detailedClassificationResults)
                        
            if ~isempty(app.detailedClassificationViewer)
                app.detailedClassificationViewer.close();
            end
            
            app.detailedClassificationViewer = AssessmentClassificationResultsViewer(app,detailedClassificationResults);
        end
        
        function outputSizeStr = getOutputSizeForMetrics(~,metrics)
            outputSize = metrics.outputSize;
            if outputSize > 1024
                outputSize = outputSize / 1024;
                units = "KB";
            else
                units = "Bytes";
            end
            outputSizeStr = sprintf('%.1f %s', outputSize,units);
        end
        
        function fillStageMetricsToTable(app,metrics,firstColumnStr,rowIdx)
            app.computationalPerformanceMetricsTable.Data{rowIdx,1} = firstColumnStr;
            app.computationalPerformanceMetricsTable.Data{rowIdx,2} = sprintf('%.1f flops per sample',metrics.flops);
            app.computationalPerformanceMetricsTable.Data{rowIdx,3} = sprintf('%.1f KB',metrics.memory / 1024);
            app.computationalPerformanceMetricsTable.Data{rowIdx,4} = app.getOutputSizeForMetrics(metrics);
        end
        
        function displayComputationalMetrics(app)
            
            totalMetrics = Metric();
            totalMetrics.flops = app.segmentationMetrics.flops + app.featureExtractionMetrics.flops + app.classificationMetrics.flops;
            totalMetrics.memory = max(max(app.segmentationMetrics.memory, app.featureExtractionMetrics.memory), app.classificationMetrics.memory);
            totalMetrics.outputSize = app.classificationMetrics.outputSize;
            
            app.computationalPerformanceMetricsTable.Data = cell(4,4);
            
            app.fillStageMetricsToTable(app.segmentationMetrics,'Segmentation',1);
            app.fillStageMetricsToTable(app.featureExtractionMetrics,'FeatureExtraction',2);
            app.fillStageMetricsToTable(app.classificationMetrics,'Classification',3);
            app.fillStageMetricsToTable(totalMetrics,'Total',4);
        end
        
        function displayClassificationResults(app)
            
            app.mainTabGroup.SelectedTab = app.algorithmAssessmentTab;
            
            app.detailedClassificationResults = app.createDetailedClassificationResults();
            
            %display confusion matrix
            confusionMatrix = app.createConfusionMatrix(app.detailedClassificationResults);
            app.plotConfusionMatrix(confusionMatrix);
            
            %display tables
            aggregatedClassificationResults = AssessmentAggregatedClassificationResults(confusionMatrix);
            app.plotAggregatedClassificationResults(aggregatedClassificationResults);
            app.updateRecognitionPerformanceMetricsTablePerFile(app.detailedClassificationResults);
            app.updateRecognitionPerformanceMetricsTablePerClass(aggregatedClassificationResults);
        end
        
        function updateIsManualMode(app)
            selectedButton = app.algorithmSourceButtonGroup.SelectedObject;
            
            if(selectedButton == app.manualSegmentationRadio)
                
                app.isManualMode = true;
            else
                app.isManualMode = false;
            end
        end
    end
    
    methods (Access = public)
        
        function handleDetailedClassificationViewerClosed(app)
            delete(app.detailedClassificationViewer);
            app.detailedClassificationViewer = [];
        end
        
        function handleValidationStarted(app)
            app.validationWaitBar = waitbar(0,'Validating...');
        end
        
        function handleValidationProgress(app,testIndex,n)
            waitBarMsg = sprintf('Validating fold %d ...',testIndex);
            waitbar(testIndex/n,app.validationWaitBar,waitBarMsg);
        end
        
        function handleValidationFinished(app)
            close(app.validationWaitBar);
            app.validationWaitBar = [];
            app.mainFigure.Visible = 'off';
            app.mainFigure.Visible = 'on';
        end
        
        function handleSelectedLabelingChanged(app,labelGrouping)
            app.postprocessingConfigurator.setCurrentLabeling(labelGrouping);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mainFigure.Visible = false;
            close all;
            clear Cache;
            movegui(app.mainFigure,'center');
            app.mainFigure.Visible = true;
            
            app.isManualMode = true;
            app.dataLoader = DataLoader();
            
            %labelMappings
            labelMappings = DataLoader.LoadAllLabelMappings();
            
            %annotations
            defaultLabeling = labelMappings(1).sourceLabeling;
            app.annotations = app.dataLoader.LoadAllAnnotations(defaultLabeling);
            
            app.manualSegmentation = ManualSegmentation(app.annotations);
            app.fileNames = Helper.listDataFiles();
            app.signalNames = Helper.LoadSignalNames();
            app.featureNormalizer = FeatureNormalizer();
            app.featureSelector = FeatureSelector();
            app.updateSelectedFeatures();
            
            app.tableExporter = TableExporter();
            app.filesMap = containers.Map();
            
            app.confusionMatrixPlotter = ConfusionMatrixPlotter(app.confusionMatrixAxes);
            
            app.loadFilesMap();
            
            app.loadConfigurators(labelMappings);
            app.populateFilesList();
            app.populateSignalsList();
            app.validationConfigurator.populateFileLists(app.inputFilesList.Value);
            app.populateChainFilesList();
        end

        % Callback function
        function moveFileRightButtonPushed(app, event)
            
            selectedFilesStr = app.popSelectedFilesFromList(app.trainFilesList);
            app.pushFilesToList(selectedFilesStr,app.testFilesList);
        end

        % Callback function
        function moveFileLeftButtonPushed(app, event)
            
            selectedFileStr = app.popSelectedFilesFromList(app.testFilesList);
            app.pushFilesToList(selectedFileStr,app.trainFilesList);
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
                
                app.isManualMode = true;
                
                app.segmentationConfigurator.computers = {app.manualSegmentation};
                app.segmentationConfigurator.reloadUI();
            else
                app.isManualMode = false;
                
                eventSegmentation = EventSegmentation();
                slidingWindowSegmentation = SlidingWindowSegmentation();
                app.segmentationConfigurator.computers = {eventSegmentation,slidingWindowSegmentation};
                app.segmentationConfigurator.reloadUI();
            end
            app.updatePreprocessingPanelVisibility();
            app.updateEventDetectionTablesVisibility();
            app.updateLabelingPanelVisibility();
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            tableNames = app.exportTablesList.Value;
            [~,idxs] = ismember(tableNames,app.exportTablesList.Items);
            
            tableNames = app.generatedTableNames(idxs);
            table = app.getGeneratedTableWithNames(tableNames);
            
            app.exportTable(table,Constants.kFeaturesTableFileName);
            
            if app.exportNormalisationValuesCheckBox.Value == 1
                app.exportNormalisationValues();
            end
        end

        % Callback function
        function validationButtonGroupSelectionChanged(app, event)
            selectedButton = app.validationButtonGroup.SelectedObject;
            if(selectedButton == app.holdoutRadio)
                app.moveFileRightButton.Visible = true;
                app.moveFileLeftButton.Visible = true;
                app.testFilesList.Visible = true;
                app.selectAllTestingCheckBox.Visible = true;
            else
                nTestItems = length(app.testFilesList.Items);
                if nTestItems > 0
                    app.trainFilesList.Items(end+1:end+nTestItems) = app.testFilesList.Items;
                end
                app.moveFileRightButton.Visible = false;
                app.moveFileLeftButton.Visible = false;
                app.testFilesList.Visible = false;
                app.selectAllTestingCheckBox.Visible = false;
            end
        end

        % Button pushed function: saveFeatures
        function saveFeaturesButtonPushed(app, event)
            featureExtractor = app.featureExtractionConfigurator.createFeatureExtractorWithUIParameters();
            app.dataLoader.SaveComputer(featureExtractor,'generatedFeatures');
        end

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            if(app.isValidInputFilesConfiguration() && app.validationConfigurator.isValidConfiguration())
                if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())
                    
                    app.buildSegmentationChain();
                    app.buildFeatureExtractionChain();
                    app.buildClassificationChain();
                    
                    segmentationSucceeded = app.executeSegmentationAndFeatureExtractionChain(app.inputFilesList);
                    
                    if segmentationSucceeded
                        
                        classificationSucceeded = app.executeClassificationChain();
                        
                        app.displayComputationalMetrics();
                            
                        if classificationSucceeded
                            
                            app.generatedTableNames = app.inputFilesList.Value;
                            
                            app.updateExportFeatureTablesList();
                            
                            app.displayClassificationResults();
                        end
                    end
                end
            end
        end

        % Value changed function: selectAllInputFilesList
        function selectAllInputFilesListValueChanged(app, event)
            value = app.selectAllInputFilesList.Value;
            if(value)
                app.inputFilesList.Value = app.inputFilesList.Items;
            else
                app.inputFilesList.Value = {};
            end
            app.validationConfigurator.populateFileLists(app.inputFilesList.Value);
        end

        % Button pushed function: findFeatures
        function findFeaturesButtonPushed(app, event)
            mergedTable = app.featureTables.mergedTables();
            nFeatures = app.nFeaturesEditField.Value;
            app.featureSelector.findBestFeaturesForTable(mergedTable,nFeatures);
            app.updateSelectedFeatures();
            
        end

        % Button pushed function: selectFeaturesButton
        function selectFeaturesButtonPushed(app, event)
            if ~isempty(app.featureTables)
                for i = 1 : app.featureTables.nTables
                    app.featureSelector.selectFeaturesForTable(app.featureTables.tables(i));
                end
                app.updateExportFeatureTablesList();
            end
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end

        % Button pushed function: visualizeARCButton
        function visualizeARCButtonPushed(app, event)
            Helper.PlotComputerGraph(app.segmentationChain);
            Helper.PlotComputerGraph(app.featureExtractionChain);
        end

        % Button pushed function: executeChainFile
        function executeChainFileButtonPushed(app, event)
            if ~isempty(app.chainFilesList.Value)
                app.buildAnnotationChain();
                app.classificationChain = app.dataLoader.LoadComputer(app.chainFilesList.Value);
                app.fileLoader = app.classificationChain;%first element of the chain should be a file loader
                
                classificationSucceeded = app.executeChainFromFile();
                app.generatedTableNames = app.inputFilesList.Value;

                if classificationSucceeded
                    
                    app.generatedTableNames = app.inputFilesList.Value;
                    
                    app.updateExportFeatureTablesList();
                    
                    app.displayClassificationResults();
                end
            end
        end

        % Value changed function: inputFilesList
        function inputFilesListValueChanged(app, event)
            app.validationConfigurator.populateFileLists(app.inputFilesList.Value);
        end

        % Selection changed function: algorithmSourceButtonGroup
        function algorithmSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.algorithmSourceButtonGroup.SelectedObject;
            if selectedButton == app.algorithmFromUserInterfaceButton
                app.algorithmFromFilePanel.Visible = false;
                app.algorithmFromUserInterfacePanel.Visible = true;
                app.updateIsManualMode();
            else
                app.algorithmFromUserInterfacePanel.Visible = false;
                app.algorithmFromFilePanel.Visible = true;
                app.isManualMode = false;
            end
        end

        % Close request function: mainFigure
        function mainFigureCloseRequest(app, event)
            if ~isempty(app.detailedClassificationViewer)
                app.detailedClassificationViewer.delegate = [];
                app.detailedClassificationViewer.close();
            end
            delete(app)
        end

        % Button pushed function: 
        % algorithmAssessmentDetailViewButton
        function algorithmAssessmentDetailViewButtonPushed(app, event)
            
            %details viewer
            app.createDetaileClassificationResultsViewer(app.detailedClassificationResults);
        end

        % Value changed function: signalsList
        function signalsListValueChanged(app, event)
            [~,indices] = ismember(app.signalsList.Value,app.signalsList.Items);
            indices = sort(indices);
            signals = app.signalsList.Items(indices);
            app.eventDetectorConfigurator.setSignals(signals);
            app.preprocessingConfigurator.setSignals(signals);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Assessment App';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @mainFigureCloseRequest, true);
            app.mainTabGroup = uitabgroup(app.mainFigure);
            app.AlgorithmDesignTab = uitab(app.mainTabGroup);
            app.AlgorithmDesignTab.Title = 'Algorithm Design';
            app.algorithmFromUserInterfacePanel = uipanel(app.AlgorithmDesignTab);
            app.segmentationPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.segmentationPanel.Title = '4-Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.eventDetectionPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.eventDetectionPanel.Title = '3-Event Detection (optional)';
            app.eventDetectionPreprocessingSignalComputerVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionPreprocessingSignalComputerVariablesTable.RowName = {};
            app.eventDetectionPreprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.eventDetectionPreprocessingSignalComputerList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalComputerList.Items = {};
            app.eventDetectionPreprocessingSignalComputerList.Value = {};
            app.eventDetectionPreprocessingSignalsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalsList.Items = {};
            app.eventDetectionPreprocessingSignalsList.Multiselect = 'on';
            app.eventDetectionPreprocessingSignalsList.Value = {};
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true];
            app.eventDetectionList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionList.Items = {};
            app.eventDetectionList.Value = {};
            app.segmentsSourceButtonGroup = uibuttongroup(app.algorithmFromUserInterfacePanel);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = '1a-Data';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual (use annotations)';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic';
            app.featureExtractionPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.featureExtractionPanel.Title = '7-Feature Extraction';
            app.shouldNormalizeFeaturesCheckBox = uicheckbox(app.featureExtractionPanel);
            app.shouldNormalizeFeaturesCheckBox.Text = 'Normalize';
            app.shouldNormalizeFeaturesCheckBox.Value = true;
            app.featuresSourceButtonGroup = uibuttongroup(app.featureExtractionPanel);
            app.featuresSourceFromFileRadio = uiradiobutton(app.featuresSourceButtonGroup);
            app.featuresSourceFromFileRadio.Text = 'File';
            app.featuresSourceFromFileRadio.Value = true;
            app.featuresSourceManuallyRadio = uiradiobutton(app.featuresSourceButtonGroup);
            app.featuresSourceManuallyRadio.Text = 'Manually';
            app.loadedFeatureExtractorsList = uilistbox(app.featureExtractionPanel);
            app.loadedFeatureExtractorsList.Items = {};
            app.loadedFeatureExtractorsList.Value = {};
            app.manualFeatureExtractionPanel = uipanel(app.featureExtractionPanel);
            app.manualFeatureExtractionPanel.Visible = 'off';
            app.defaultFeaturesList = uilistbox(app.manualFeatureExtractionPanel);
            app.defaultFeaturesList.Items = {};
            app.defaultFeaturesList.Value = {};
            app.selectedFeaturesList = uilistbox(app.manualFeatureExtractionPanel);
            app.selectedFeaturesList.Items = {};
            app.selectedFeaturesList.Value = {};
            app.addFeatureButton = uibutton(app.manualFeatureExtractionPanel, 'push');
            app.addFeatureButton.Text = '+';
            app.removeFeatureButton = uibutton(app.manualFeatureExtractionPanel, 'push');
            app.removeFeatureButton.Text = '-';
            app.StartEditFieldLabel = uilabel(app.manualFeatureExtractionPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.featureStartRangeEditText = uieditfield(app.manualFeatureExtractionPanel, 'numeric');
            app.featureStartRangeEditText.Value = 1;
            app.EndEditFieldLabel = uilabel(app.manualFeatureExtractionPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.featureEndRangeEditText = uieditfield(app.manualFeatureExtractionPanel, 'numeric');
            app.featureEndRangeEditText.Value = 350;
            app.featureFullSegmentCheckBox = uicheckbox(app.manualFeatureExtractionPanel);
            app.featureFullSegmentCheckBox.Text = 'Full Segment';
            app.featureFullSegmentCheckBox.Value = true;
            app.AxesLabel = uilabel(app.manualFeatureExtractionPanel);
            app.AxesLabel.Text = 'Axes';
            app.featureAxisEditText = uieditfield(app.manualFeatureExtractionPanel, 'text');
            app.saveFeatures = uibutton(app.featureExtractionPanel, 'push');
            app.saveFeatures.ButtonPushedFcn = createCallbackFcn(app, @saveFeaturesButtonPushed, true);
            app.saveFeatures.Text = 'Save to file';
            app.manualAnnotationsTypePanel = uibuttongroup(app.algorithmFromUserInterfacePanel);
            app.manualAnnotationsTypePanel.Title = '2-Annotation Type';
            app.includeEventsCheckBox = uicheckbox(app.manualAnnotationsTypePanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.manualAnnotationsTypePanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.includeLabelsPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.includeLabelsPanel.Title = '10-Classification';
            app.classifiersList = uilistbox(app.includeLabelsPanel);
            app.classifiersList.Items = {};
            app.classifiersList.Multiselect = 'on';
            app.classifiersList.Value = {};
            app.classifiersVariablesTable = uitable(app.includeLabelsPanel);
            app.classifiersVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.classifiersVariablesTable.RowName = {};
            app.classifiersVariablesTable.ColumnEditable = [false true];
            app.featureSelectionPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.featureSelectionPanel.Title = '8-Feature Selection (optional)';
            app.findFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.findFeatures.ButtonPushedFcn = createCallbackFcn(app, @findFeaturesButtonPushed, true);
            app.findFeatures.Text = 'Find';
            app.printFeatures = uibutton(app.featureSelectionPanel, 'push');
            app.printFeatures.Text = 'Print';
            app.selectFeaturesButton = uibutton(app.featureSelectionPanel, 'push');
            app.selectFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @selectFeaturesButtonPushed, true);
            app.selectFeaturesButton.Text = 'Select';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.featureSelectionPanel);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features';
            app.nFeaturesEditField = uieditfield(app.featureSelectionPanel, 'numeric');
            app.nFeaturesEditField.Value = 20;
            app.featuresTextBox = uitextarea(app.featureSelectionPanel);
            app.labelingPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.labelingPanel.Title = '5-Labeling';
            app.labelingPanel.Visible = 'off';
            app.labelingStrategiesList = uilistbox(app.labelingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.labelingVariablesTable = uitable(app.labelingPanel);
            app.labelingVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.labelingVariablesTable.RowName = {};
            app.labelingVariablesTable.ColumnEditable = [false true];
            app.validationPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.validationPanel.Title = '9-Validation';
            app.testFilesList = uilistbox(app.validationPanel);
            app.testFilesList.Items = {};
            app.testFilesList.Multiselect = 'on';
            app.testFilesList.Value = {};
            app.trainFilesList = uilistbox(app.validationPanel);
            app.trainFilesList.Items = {};
            app.trainFilesList.Multiselect = 'on';
            app.trainFilesList.Value = {};
            app.moveFileRightButton = uibutton(app.validationPanel, 'push');
            app.moveFileRightButton.Text = '>>';
            app.moveFileLeftButton = uibutton(app.validationPanel, 'push');
            app.moveFileLeftButton.Text = '<<';
            app.selectAllTrainingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllTrainingCheckBox.Text = 'select all';
            app.selectAllTestingCheckBox = uicheckbox(app.validationPanel);
            app.selectAllTestingCheckBox.Text = 'select all';
            app.validationButtonGroup = uibuttongroup(app.validationPanel);
            app.holdoutRadio = uiradiobutton(app.validationButtonGroup);
            app.holdoutRadio.Text = 'Holdout';
            app.holdoutRadio.Value = true;
            app.crossValidationRadio = uiradiobutton(app.validationButtonGroup);
            app.crossValidationRadio.Text = 'Cross Validation';
            app.postProcessingPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.postProcessingPanel.Title = '11-Postprocessing';
            app.postprocessingList = uilistbox(app.postProcessingPanel);
            app.postprocessingList.Items = {};
            app.postprocessingList.Multiselect = 'on';
            app.postprocessingList.Value = {};
            app.postprocessingVariablesTable = uitable(app.postProcessingPanel);
            app.postprocessingVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.postprocessingVariablesTable.RowName = {};
            app.postprocessingVariablesTable.ColumnEditable = [false true];
            app.annotationsPanelPostProcessing = uipanel(app.postProcessingPanel);
            app.annotationMappingListPostprocessing = uilistbox(app.annotationsPanelPostProcessing);
            app.annotationMappingListPostprocessing.Items = {};
            app.annotationMappingListPostprocessing.Value = {};
            app.annotationMappingTablePostProcessing = uitable(app.annotationsPanelPostProcessing);
            app.annotationMappingTablePostProcessing.ColumnName = {'Source'; 'Target'};
            app.annotationMappingTablePostProcessing.RowName = {};
            app.annotationMappingTablePostProcessing.ColumnEditable = [false true];
            app.preprocessingPanel = uipanel(app.algorithmFromUserInterfacePanel);
            app.preprocessingPanel.Title = '2-Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.algorithmSourceButtonGroup = uibuttongroup(app.AlgorithmDesignTab);
            app.algorithmSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @algorithmSourceButtonGroupSelectionChanged, true);
            app.algorithmSourceButtonGroup.Title = 'Algorithm Source';
            app.algorithmFromUserInterfaceButton = uiradiobutton(app.algorithmSourceButtonGroup);
            app.algorithmFromUserInterfaceButton.Text = 'User Interface';
            app.algorithmFromUserInterfaceButton.Value = true;
            app.algorithmFromFileButton = uiradiobutton(app.algorithmSourceButtonGroup);
            app.algorithmFromFileButton.Text = 'File';
            app.algorithmFromFilePanel = uipanel(app.AlgorithmDesignTab);
            app.algorithmFromFilePanel.Visible = 'off';
            app.visualizeARCButton = uibutton(app.algorithmFromFilePanel, 'push');
            app.visualizeARCButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeARCButtonPushed, true);
            app.visualizeARCButton.Text = 'Visualize';
            app.executeChainFile = uibutton(app.algorithmFromFilePanel, 'push');
            app.executeChainFile.ButtonPushedFcn = createCallbackFcn(app, @executeChainFileButtonPushed, true);
            app.executeChainFile.Text = 'Execute';
            app.chainFilesList = uilistbox(app.algorithmFromFilePanel);
            app.chainFilesList.Items = {};
            app.chainFilesList.Value = {};
            app.Label = uilabel(app.algorithmFromFilePanel);
            app.Label.Text = {'first computer should be: FileLoader'; 'last computer should be: Validator'};
            app.clearCacheButton = uibutton(app.AlgorithmDesignTab, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            app.executeButton = uibutton(app.AlgorithmDesignTab, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'Execute';
            app.filesPanel = uipanel(app.AlgorithmDesignTab);
            app.filesPanel.Title = '1-Data Source';
            app.inputFilesList = uilistbox(app.filesPanel);
            app.inputFilesList.Items = {};
            app.inputFilesList.Multiselect = 'on';
            app.inputFilesList.ValueChangedFcn = createCallbackFcn(app, @inputFilesListValueChanged, true);
            app.inputFilesList.Value = {};
            app.selectAllInputFilesList = uicheckbox(app.filesPanel);
            app.selectAllInputFilesList.ValueChangedFcn = createCallbackFcn(app, @selectAllInputFilesListValueChanged, true);
            app.selectAllInputFilesList.Text = 'select all';
            app.signalsList = uilistbox(app.filesPanel);
            app.signalsList.Items = {};
            app.signalsList.Multiselect = 'on';
            app.signalsList.ValueChangedFcn = createCallbackFcn(app, @signalsListValueChanged, true);
            app.signalsList.Value = {};
            app.annotationsPanel = uipanel(app.AlgorithmDesignTab);
            app.annotationsPanel.Title = '1b- Annotations';
            app.annotationMappingList = uilistbox(app.annotationsPanel);
            app.annotationMappingList.Items = {};
            app.annotationMappingList.Value = {};
            app.annotationMappingTable = uitable(app.annotationsPanel);
            app.annotationMappingTable.ColumnName = {'Source'; 'Target'};
            app.annotationMappingTable.RowName = {};
            app.annotationMappingTable.ColumnEditable = [false true];
            app.algorithmAssessmentTab = uitab(app.mainTabGroup);
            app.algorithmAssessmentTab.Title = 'Algorithm Assessment';
            app.recognitionPerformanceMetricsPanel = uipanel(app.algorithmAssessmentTab);
            app.recognitionPerformanceMetricsPanel.Title = 'Recognition Performance per File';
            app.performanceMetricsPerFileTable = uitable(app.recognitionPerformanceMetricsPanel);
            app.performanceMetricsPerFileTable.ColumnName = {'File'; 'Accuracy'; 'Precision'; 'Recall'; 'F1-Score'};
            app.performanceMetricsPerFileTable.RowName = {};
            app.performanceMetricsPerFileTable.ColumnEditable = [true false false false false];
            app.recognitionPerformanceMetricsPerClassPanel = uipanel(app.algorithmAssessmentTab);
            app.recognitionPerformanceMetricsPerClassPanel.Title = 'Recognition Performance per Class';
            app.performanceMetricsPerClassTable = uitable(app.recognitionPerformanceMetricsPerClassPanel);
            app.performanceMetricsPerClassTable.ColumnName = {'Class'; 'Precision'; 'Recall'};
            app.performanceMetricsPerClassTable.RowName = {};
            app.performanceMetricsPerClassTable.ColumnEditable = false;
            app.confusionMatrixAxes = uiaxes(app.algorithmAssessmentTab);
            title(app.confusionMatrixAxes, 'Title')
            xlabel(app.confusionMatrixAxes, 'X')
            ylabel(app.confusionMatrixAxes, 'Y')
            app.confusionMatrixAxes.Visible = 'off';
            app.computationalPerformanceMetricsPerClassPanel = uipanel(app.algorithmAssessmentTab);
            app.computationalPerformanceMetricsPerClassPanel.Title = 'Computational Performance';
            app.computationalPerformanceMetricsTable = uitable(app.computationalPerformanceMetricsPerClassPanel);
            app.computationalPerformanceMetricsTable.ColumnName = {'Stage'; 'Execution cost'; 'Memory cost'; 'Output'};
            app.computationalPerformanceMetricsTable.RowName = {};
            app.computationalPerformanceMetricsTable.ColumnEditable = false;
            app.algorithmAssessmentDetailViewButton = uibutton(app.algorithmAssessmentTab, 'push');
            app.algorithmAssessmentDetailViewButton.ButtonPushedFcn = createCallbackFcn(app, @algorithmAssessmentDetailViewButtonPushed, true);
            app.algorithmAssessmentDetailViewButton.Text = 'Algorithm Assessment Detail View...';
            app.ExportTab = uitab(app.mainTabGroup);
            app.ExportTab.Title = 'Export';
            app.exportPanel = uipanel(app.ExportTab);
            app.exportPanel.Title = 'Export';
            app.exportNormalisationValuesCheckBox = uicheckbox(app.exportPanel);
            app.exportNormalisationValuesCheckBox.Text = 'Norms';
            app.exportFormatGroup = uibuttongroup(app.exportPanel);
            app.exportFormatGroup.Title = 'Format';
            app.exportMatlabFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportMatlabFormatCheckBox.Text = '.mat';
            app.exportTextFormatCheckBox = uiradiobutton(app.exportFormatGroup);
            app.exportTextFormatCheckBox.Text = '.txt';
            app.exportTextFormatCheckBox.Value = true;
            app.exportLabelTypeGroup = uibuttongroup(app.exportPanel);
            app.exportLabelTypeGroup.Title = 'Table Labels';
            app.exportTextLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportTextLabelsRadio.Text = 'text';
            app.exportTextLabelsRadio.Value = true;
            app.exportNumberLabelsRadio = uiradiobutton(app.exportLabelTypeGroup);
            app.exportNumberLabelsRadio.Text = 'number';
            app.exportButton = uibutton(app.exportPanel, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'Export';
            app.exportTablesList = uilistbox(app.exportPanel);
            app.exportTablesList.Items = {};
            app.exportTablesList.Multiselect = 'on';
            app.exportTablesList.Value = {};
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AssessmentApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end